classdef GUI_v3_08 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPOT30GUIUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveGUIMenu                     matlab.ui.container.Menu
        LabLinksMenu                    matlab.ui.container.Menu
        GitHubMenu                      matlab.ui.container.Menu
        GooglePhotosMenu                matlab.ui.container.Menu
        YoutubeChannelMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        YouTubeTutorialsMenu            matlab.ui.container.Menu
        RunningaSimulationandExperimentMenu  matlab.ui.container.Menu
        ConnectingtoaRaspberryPiRemotelyMenu  matlab.ui.container.Menu
        RemovingtheTableCoverMenu       matlab.ui.container.Menu
        RetrievingtheIsopropanolMenu    matlab.ui.container.Menu
        TableCleaningProceduresMenu     matlab.ui.container.Menu
        SavingExperimentDataMenu        matlab.ui.container.Menu
        RemovingtheRoboticArmMenu       matlab.ui.container.Menu
        ChargingaLEDBatteryPackMenu     matlab.ui.container.Menu
        ChargingaPlatformMenu           matlab.ui.container.Menu
        InstallinganAirTankMenu         matlab.ui.container.Menu
        InstallingtheRoboticArmMenu     matlab.ui.container.Menu
        ForkingtheSPOTRepositoryMenu    matlab.ui.container.Menu
        PlacingtheTableCoverMenu        matlab.ui.container.Menu
        FillinganAirTankMenu            matlab.ui.container.Menu
        ExportingVideoswithQCViewerMenu  matlab.ui.container.Menu
        CleaningthePucksandMovingaPlatformMenu  matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        CheckArmConfigurationMenu       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        MassPropertiesPanel             matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        UITableGeometry                 matlab.ui.control.Table
        UITableArmAngle                 matlab.ui.control.Table
        ShowDefsButton                  matlab.ui.control.Button
        ConfirmOptionsButton            matlab.ui.control.Button
        LoadSelectedDiagramButton       matlab.ui.control.Button
        PlatformControlsPanel           matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        ExperimentOptionsPanel          matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        StartExperimentButton           matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        SaveExperimentButton            matlab.ui.control.Button
        RealTimeTelemetryCheckBox       matlab.ui.control.CheckBox
        DeployCodeREDButton             matlab.ui.control.Button
        CopyCodeToPlatformsButton       matlab.ui.control.Button
        RefreshSoftwareonSelectedPlatformsButton  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        REDControlsTab                  matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        REDPucksOnButton                matlab.ui.control.Button
        REDPucksOffButton               matlab.ui.control.Button
        REDDebuggingPanel               matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        FireREDThrustersButton          matlab.ui.control.Button
        PingREDButton                   matlab.ui.control.Button
        REDTextArea                     matlab.ui.control.TextArea
        OpenREDCMDShell                 matlab.ui.control.Button
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        REDPingTimeout                  matlab.ui.control.NumericEditField
        REDConnectedLamp                matlab.ui.control.Lamp
        BLACKControlsTab                matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        BLACKPucksOnButton              matlab.ui.control.Button
        BLACKPucksOffButton             matlab.ui.control.Button
        BLACKDebuggingPanel             matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        FireBLACKThrustersButton        matlab.ui.control.Button
        PingBLACKButton                 matlab.ui.control.Button
        BLACKConnectedLamp              matlab.ui.control.Lamp
        BLACKTextArea                   matlab.ui.control.TextArea
        OpenBLACKCMDShell               matlab.ui.control.Button
        TimeoutsEditFieldLabel_2        matlab.ui.control.Label
        BLACKPingTimeout                matlab.ui.control.NumericEditField
        BLUEControlsTab                 matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        BLUEPucksOnButton               matlab.ui.control.Button
        BLUEPucksOffButton              matlab.ui.control.Button
        BLUEDebuggingPanel              matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        FireBLUEThrustersButton         matlab.ui.control.Button
        PingBLUEButton                  matlab.ui.control.Button
        BLUETextArea                    matlab.ui.control.TextArea
        BLUEConnectedLamp               matlab.ui.control.Lamp
        OpenBLUECMDShell                matlab.ui.control.Button
        TimeoutsEditFieldLabel_3        matlab.ui.control.Label
        BLUEPingTimeout                 matlab.ui.control.NumericEditField
        SimulationPanel                 matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ExperimentPreview               matlab.ui.control.UIAxes
        SimulationControlsPanel         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        RunSimulationButton             matlab.ui.control.Button
        PlayAnimationButton             matlab.ui.control.Button
        AnimationSpeedButtonGroup       matlab.ui.container.ButtonGroup
        AnimationSpeedSlider            matlab.ui.control.Slider
        StopAnimationButton             matlab.ui.control.StateButton
        SimExportOptionsPanel           matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        CreateVideoCheckBox             matlab.ui.control.CheckBox
        SaveSimulationButton            matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        AnimationScrubberLabel          matlab.ui.control.Label
        AvailableDiagramsDropDownLabel  matlab.ui.control.Label
        AvailableDiagramsDropDown       matlab.ui.control.DropDown
        ActivePlatformsPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        REDCheckBox                     matlab.ui.control.CheckBox
        BLACKCheckBox                   matlab.ui.control.CheckBox
        BLUECheckBox                    matlab.ui.control.CheckBox
        ARMCheckBox                     matlab.ui.control.CheckBox
        AuxParametersPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TimeLabel                       matlab.ui.control.Label
        TotalTimeEditField              matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        RateLabel                       matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
    end

    
properties (Access = private)
        REDFigHdl   = [];
        BLACKFigHdl = [];
        BLUEFigHdl  = [];
        REDFigLine  = [];
        BLACKFigLine= [];
        BLUEFigLine = [];
        REDFigLink1 = [];
        REDFigLink2 = [];
        REDFigLink3 = [];
        REDFigJoin0 = [];
        REDFigJoin1 = [];
        REDFigJoin2 = [];
        REDFigJoin3 = []; 
        DataPacketUp = [];
        FloatLocationMod = [];
        CleanShutdownLocationMod = [];
        DiagramLocationMod = [];
        Interruptor = 0;
    end

    methods (Access = private)

        function [] = DirectoryBuilder(app)
        
            UpDir            = dir('..');
            UpUpDir          = dir('../..');
            
            FoundMainDir     = false;
            
            for i = 1:length(UpUpDir)
            
                if UpUpDir(i).name == "Useful_Code"
            
                    FoundMainDir = true;
                    MainDirectoryLocation = UpUpDir(1).folder;
                    
                end
            
            end
            
            if FoundMainDir == false
            
                for i = 1:length(UpDir)
                
                    if UpDir(i).name == "Useful_Code"
                
                        FoundMainDir = true;
                        MainDirectoryLocation = UpDir(1).folder;
                        
                    end
                
                end
            
            end
            
            if FoundMainDir == false
            
                warndlg('Unable to located main directory.','Directory Error');
            
            else
            
                FloatLocation            = [MainDirectoryLocation '\Useful_Code\FloatPlatform'];
                CleanShutdownLocation    = [MainDirectoryLocation '\Useful_Code\CleanShutdown'];
                DiagramLocation          = cd;
            
                FloatLocationTemp         = erase(FloatLocation,':');
                CleanShutdownLocationTemp = erase(CleanShutdownLocation,':');
                DiagramLocationTemp       = erase(DiagramLocation,':');
    
                app.FloatLocationMod         = strrep(FloatLocationTemp,'\','/');
                app.CleanShutdownLocationMod = strrep(CleanShutdownLocationTemp,'\','/');
                app.DiagramLocationMod       = strrep(DiagramLocationTemp,'\','/');
            
            end


        end
        
        function [] = AutoGenerateBatchCommands(app)
            
            fid = fopen([cd '\Batch_Scripts\CopyDirToWinAuto.bat'],'wt');
            
            fprintf(fid, 'cd C:\\Program Files\\PuTTY \n');
            FileStorage = [cd '\Pi_Files'];
            test = strrep(FileStorage,'\','\\');
            fprintf(fid, ['pscp.exe -pw raspberry -r pi@192.168.0.106:/home/pi/ExperimentSoftware/* ' test '\n']);
            fclose(fid);
            
            fid = fopen([cd '\Batch_Scripts\CopyDirToREDAuto.bat'],'wt');
            fprintf(fid, 'cd C:\\Program Files\\PuTTY \n');
            if (app.REDCheckBox.Value == 1)
                fprintf(fid, ['pscp.exe -pw raspberry -r ' test '\\MATLAB_ws pi@192.168.0.100:/home/pi/ExperimentSoftware \n']);
            end

            fid = fopen([cd '\Batch_Scripts\CopyDirToBLACKAuto.bat'],'wt');
            fprintf(fid, 'cd C:\\Program Files\\PuTTY \n');
            if (app.BLACKCheckBox.Value == 1)
                fprintf(fid, ['pscp.exe -pw raspberry -r ' test '\\MATLAB_ws pi@192.168.0.101:/home/pi/ExperimentSoftware \n']);
            end
            fclose(fid);
            
            fid = fopen([cd '\Batch_Scripts\CopyDirToBLUEAuto.bat'],'wt');
            fprintf(fid, 'cd C:\\Program Files\\PuTTY \n');
            if (app.BLUECheckBox.Value == 1)
                fprintf(fid, ['pscp.exe -pw raspberry -r ' test '\\MATLAB_ws pi@192.168.0.102:/home/pi/ExperimentSoftware \n']);
            end            
            fclose(fid);

           
        end
        
        function [] = CustomRunModel(~, Platform, DiagramName)

            fid = fopen([cd '\Batch_Scripts\CustomRunModel.bat'],'wt');
            fprintf(fid, ['plink.exe -ssh pi@' Platform.DeviceAddress ' -pw raspberry command "nohup sudo ./ExperimentSoftware/' DiagramName '.elf >/dev/null 2>&1 &"']);
            fclose(fid);

            system([cd '\Batch_Scripts\CustomRunModel.bat >nul 2>&10']);

        end

        function [] = CustomStopModel(~, Platform, DiagramName)

            %system(Platform, ['sudo killall ' DiagramName '.elf']);
            
            fid = fopen([cd '\Batch_Scripts\CustomStopModel.bat'],'wt');
            fprintf(fid, ['plink.exe -ssh pi@' Platform.DeviceAddress ' -pw raspberry command "nohup sudo killall ./ExperimentSoftware/' DiagramName '.elf >/dev/null 2>&1 &"']);
            fclose(fid);

            system([cd '\Batch_Scripts\CustomStopModel.bat >nul 2>&10']);


        end  
        
        function [] = CustomStartCameras(~,ExpRate)

            %system(Platform, ['sudo killall ' DiagramName '.elf']);
            current_path = cd;
            current_path = strrep(current_path, "\", "\\");
            directory = char([current_path + "\\Resources\\"]);
            fid = fopen([cd '\Batch_Scripts\CustomRunCameras.bat'],'wt');
            fprintf(fid, ['cd ' directory '\n']);
            fprintf(fid, ['start StreamData.exe ' num2str(ExpRate)]);
            fclose(fid);

            system([cd '\Batch_Scripts\CustomRunCameras.bat >nul 2>&10']);

        end 
        
        function CustomStopCameras(~)
            
            system(["taskkill/im StreamData.exe /F"]);
            
        end
        
        function is_open = isSLXOpen(~,file_name)
            % Function to check if an SLX file is already open in MATLAB
            % Inputs:
            %   file_path: string representing the full file path to the SLX file
            % Outputs:
            %   is_open: boolean indicating whether the SLX file is open or not
            
            % Remove the '.slx'
            file_name = strrep(file_name,".slx","");    
            
            % Get a list of all open models
            open_models = find_system('Type', 'block_diagram');
            
            % Loop through the open models and check if the file path matches
            for i = 1:length(open_models)
                [~, open_filename, ~] = fileparts(get_param(open_models{i}, 'FileName'));
                if strcmp(string(open_filename), file_name)
                    % If the file path matches, the file is already open
                    is_open = true;
                    return;
                end
            end
            
            % If we reach this point, the file is not open
            is_open = false;
            
        end
                               
        function [] = AddPlatform(app,platformSelection)
            
            app.ExperimentPreview.cla;
            app.REDFigHdl = []; 
            app.BLACKFigHdl = [];
            app.BLUEFigHdl = [];
            app.REDFigLine  = [];
            app.BLACKFigLine= [];
            app.BLUEFigLine = [];
            app.REDFigLink1 = [];
            app.REDFigLink2 = [];
            app.REDFigLink3 = [];
            app.REDFigJoin0 = [];
            app.REDFigJoin1 = [];
            app.REDFigJoin2 = [];
            app.REDFigJoin3 = [];

            % Initialize the components of the GUI:
            xLength = 3.51155;  %[m]
            yLength = 2.41935;  %[m]
                        
            % Flip the axis to match the table:
            axis(app.ExperimentPreview,"ij");
            
            % Set the remaining axes properties:
            app.ExperimentPreview.XAxisLocation = "top";
            app.ExperimentPreview.XLim          = [-0.65 3];
            app.ExperimentPreview.YLim          = [-0.5 4];
            
            % Plot the table:
            line(app.ExperimentPreview,[0 yLength],[0 0],"Color","black","LineWidth",2);
            hold(app.ExperimentPreview,"on");
            line(app.ExperimentPreview,[yLength yLength],[0 xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[yLength 0],[xLength xLength],"Color","black","LineWidth",2);
            line(app.ExperimentPreview,[0 0],[xLength 0],"Color","black","LineWidth",2);
            grid(app.ExperimentPreview,"on");    
            
            % Get the drop states for all three platforms:
            drop_states_RED   = evalin("base","drop_states_RED");
            drop_states_ARM   = evalin("base","drop_states_ARM");
            drop_states_BLACK = evalin("base","drop_states_BLACK");
            drop_states_BLUE  = evalin("base","drop_states_BLUE");
            model_param       = evalin("base","model_param");
            
            % For animations purposes, invert the angles:
            drop_states_RED(3)  = -drop_states_RED(3);
            drop_states_BLACK(3)= -drop_states_BLACK(3);
            drop_states_BLUE(3) = -drop_states_BLUE(3);
            
            % Draw the line showing the direction of the front face:
            C_bI_RED = [  cos(drop_states_RED(3)) sin(drop_states_RED(3))
                         -sin(drop_states_RED(3)) cos(drop_states_RED(3)) ];
                     
            Vec_RED  = C_bI_RED*[ 0.15
                                  0.0 ];
                              
            C_bI_BLACK = [  cos(drop_states_BLACK(3)) sin(drop_states_BLACK(3))
                           -sin(drop_states_BLACK(3)) cos(drop_states_BLACK(3)) ];
                     
            Vec_BLACK  = C_bI_BLACK*[ 0.15
                                      0.0 ];    
                              
            C_bI_BLUE = [  cos(drop_states_BLUE(3)) sin(drop_states_BLUE(3))
                          -sin(drop_states_BLUE(3)) cos(drop_states_BLUE(3)) ];
                     
            Vec_BLUE  = C_bI_BLUE*[ 0.15
                                    0.0 ];                              
            x0 = drop_states_RED(2);
            y0 = drop_states_RED(1);
            q0 = drop_states_RED(3);
            q1 = pi/2+drop_states_ARM(1);
            q2 = -drop_states_ARM(2);
            q3 = drop_states_ARM(3);        
            phi = pi/2-model_param(7);
            b0  = model_param(8);
            a1  = model_param(9);
            b1  = model_param(10);              
            a2  = model_param(11);                
            b2  = model_param(12);               
            a3  = model_param(13);  
            b3  = model_param(14);  
            
            rs                      = [ x0 + (b0)*cos(phi + q0),y0 + (b0)*sin(phi + q0) ];                        
                        
            re                      = [ x0 + (b0)*cos(phi + q0) + (a1+b1)*cos(pi/2 + q0 + q1),y0 + (b0)*sin(phi + q0) + (a1+b1)*sin(pi/2 + q0 + q1) ];

            rw                      = [ x0 + (b0)*cos(phi + q0) + (a1+b1)*cos(pi/2 + q0 + q1) + (a2+b2)*cos(pi/2 + q0 + q1 + q2),y0 + (b0)*sin(phi + q0) + (a1+b1)*sin(pi/2 + q0 + q1) + (a2+b2)*sin(pi/2 + q0 + q1 + q2) ];

            ree                     = [ x0 + (b0)*cos(phi + q0) + (a1+b1)*cos(pi/2 + q0 + q1) + (a2+b2)*cos(pi/2 + q0 + q1 + q2) + (a3+b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (b0)*sin(phi + q0) + (a1+b1)*sin(pi/2 + q0 + q1) + (a2+b2)*sin(pi/2 + q0 + q1 + q2) + (a3+b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                                
                                
            % Draw the relevant platforms:
            if (platformSelection == 1)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                
            elseif (platformSelection == 2)
                
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k");   
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 3)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                
            elseif (platformSelection == 4)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 5)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k');
                
            elseif (platformSelection == 6)
                
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 7)
                
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 8)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                
            elseif (platformSelection == 9)
                
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                                   
            elseif (platformSelection == 10)
                
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b"); 
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k'); 
                
            elseif (platformSelection == 11)
                
                app.REDFigHdl = DrawRectanglePrivate(app,[drop_states_RED(2),drop_states_RED(1),0.3,0.3,drop_states_RED(3)],"r");
                app.BLUEFigHdl = DrawRectanglePrivate(app,[drop_states_BLUE(2),drop_states_BLUE(1),0.3,0.3,drop_states_BLUE(3)],"b");         
                app.BLACKFigHdl = DrawRectanglePrivate(app,[drop_states_BLACK(2),drop_states_BLACK(1),0.3,0.3,drop_states_BLACK(3)],"k"); 
                app.REDFigLine = line(app.ExperimentPreview,[drop_states_RED(2),drop_states_RED(2)+Vec_RED(2)],[drop_states_RED(1),drop_states_RED(1)+Vec_RED(1)],'Color','red');
                app.BLACKFigLine = line(app.ExperimentPreview,[drop_states_BLACK(2),drop_states_BLACK(2)+Vec_BLACK(2)],[drop_states_BLACK(1),drop_states_BLACK(1)+Vec_BLACK(1)],'Color','black');     
                app.BLUEFigLine = line(app.ExperimentPreview,[drop_states_BLUE(2),drop_states_BLUE(2)+Vec_BLUE(2)],[drop_states_BLUE(1),drop_states_BLUE(1)+Vec_BLUE(1)],'Color','blue');
                app.REDFigLink1 = line(app.ExperimentPreview,[rs(1,1)  re(1,1)],[rs(1,2) re(1,2)],'Color','red');
                app.REDFigLink2 = line(app.ExperimentPreview,[re(1,1)  rw(1,1)],[re(1,2) rw(1,2)],'Color','red');
                app.REDFigLink3 = line(app.ExperimentPreview,[rw(1,1) ree(1,1)],[rw(1,2) ree(1,2)],'Color','red');
                app.REDFigJoin0 = scatter(app.ExperimentPreview,rs(1,1),rs(1,2),'filled','k');
                app.REDFigJoin1 = scatter(app.ExperimentPreview,re(1,1),re(1,2),'filled','k');
                app.REDFigJoin2 = scatter(app.ExperimentPreview,rw(1,1),rw(1,2),'filled','k');
                app.REDFigJoin3 = scatter(app.ExperimentPreview,ree(1,1),ree(1,2),'filled','k');
                
            end


        end
        
        function [FigHdl] = DrawRectanglePrivate(app,param,style)
            
            if (nargin <1)
                error("Please see help for INPUT DATA.");
            elseif (nargin==1)
                style="b";
            end
            
            [m,n] = size(param);
            
            if(m ~= 1 || n ~= 5)
                error("param should be an 1x5 array.");
            end
            
            if(param(3) <=0 || param(4) <=0)
                error("width and height must be positive values.");
            end
            
            a = param(1);
            b = param(2);
            w = param(3);
            h = param(4);
            theta = param(5);
            
            X = [-w/2 w/2 w/2 -w/2 -w/2];
            Y = [h/2 h/2 -h/2 -h/2 h/2];
            P = [X;Y];
            ct = cos(theta);
            st = sin(theta);
            R = [ct -st;st ct];
            P = R * P;
            
            FigHdl = plot(app.ExperimentPreview,P(1,:)+a,P(2,:)+b,style);  
            
        end
        
        function [] = CombineData(~,DirName)
            
            Raspberrypi_MAT_stitcher(dir(DirName));
            
        end
        
        
        function [] = ApplyDataDictionary(~,dataPacket)
            
            dataLabels = {'Time_s'...
                      'RED_Fx_Sat_N' ...
                      'RED_Fy_Sat_N' ...
                      'RED_Tz_Sat_Nm' ...
                      'RED_Px_m' ...
                      'RED_Py_m' ...
                      'RED_Rz_m' ...
                      'RED_Vx_mpers' ...
                      'RED_Vy_mpers' ...
                      'RED_RzD_radpers' ...
                      'RED_AHRS_Q_radpers' ...
                      'RED_AHRS_P_radpers' ...
                      'RED_AHRS_R_radpers' ...
                      'RED_IMU_Ax_b_mpers2' ...
                      'RED_IMU_Ay_b_mpers2' ...
                      'RED_IMU_Az_b_mpers2' ...
                      'RED_IMU_Ax_I_mpers2' ...
                      'RED_IMU_Ay_I_mpers2' ...
                      'RED_Ax_mpers2' ...
                      'RED_Ay_mpers2' ...
                      'RED_RzDD_radpers2' ...
                      'BLACK_Fx_Sat_N' ...
                      'BLACK_Fy_Sat_N' ...
                      'BLACK_Tz_Sat_Nm' ...
                      'BLACK_Px_m' ...
                      'BLACK_Py_m' ...
                      'BLACK_Rz_m' ...
                      'BLACK_Vx_mpers' ...
                      'BLACK_Vy_mpers' ...
                      'BLACK_RzD_radpers' ...
                      'BLACK_AHRS_Q_radpers' ...
                      'BLACK_AHRS_P_radpers' ...
                      'BLACK_AHRS_R_radpers' ...
                      'BLACK_IMU_Ax_b_mpers2' ...
                      'BLACK_IMU_Ay_b_mpers2' ...
                      'BLACK_IMU_Az_b_mpers2' ...
                      'BLACK_IMU_Ax_I_mpers2' ...
                      'BLACK_IMU_Ay_I_mpers2' ...
                      'BLACK_Ax_mpers2' ...
                      'BLACK_Ay_mpers2' ...
                      'BLACK_RzDD_radpers2' ...
                      'BLUE_Fx_Sat_N' ...
                      'BLUE_Fy_Sat_N' ...
                      'BLUE_Tz_Sat_Nm' ...
                      'BLUE_Px_m' ...
                      'BLUE_Py_m' ...
                      'BLUE_Rz_m' ...
                      'BLUE_Vx_mpers' ...
                      'BLUE_Vy_mpers' ...
                      'BLUE_RzD_radpers' ...
                      'RED_GyroX_Raw_radpers' ...
                      'RED_GyroY_Raw_radpers' ...
                      'RED_GyroZ_Raw_radpers' ...
                      'BLACK_GyroX_Raw_radpers' ...
                      'BLACK_GyroY_Raw_radpers' ...
                      'BLACK_GyroZ_Raw_radpers' ...
                      'RED_IMU_Psi_rad' ...
                      'BLACK_IMU_Psi_rad' ...
                      'ARM_Elbow_Px_m' ...
                      'ARM_Elbow_Py_m' ...
                      'ARM_Wrist_Px_m' ...
                      'ARM_Wrist_Py_m' ...
                      'ARM_EndEff_Px_m' ...
                      'ARM_EndEff_Py_m' ...
                      'ARM_Shoulder_Rz_rad' ...
                      'ARM_Elbow_Rz_rad' ...
                      'ARM_Wrist_Rz_rad' ...
                      'ARM_Shoulder_RzD_radpers' ...
                      'ARM_Elbow_RzD_radpers' ...
                      'ARM_Wrist_RzD_radpers' ...
                      'ARM_Shoulder_Tz_Nm' ...
                      'ARM_Elbow_Tz_Nm' ...
                      'ARM_Wrist_Tz_Nm'...
                      'RED_Desired_Ax_mpers2'...
                      'RED_Desired_Ay_mpers2'...
                      'RED_Desired_Alpha_radpers2'...
                      'ZED_Relative_X_m'...
                      'ZED_Relative_Y_m'...
                      'ZED_Relative_Yaw_rad'...
                      'ZED_Confidence'};
                                  
                for sizePacket = 1:size(dataPacket,2)
                    eval(['dataClass.' dataLabels{sizePacket} ' = dataPacket(:,sizePacket);'])
                end
                
                assignin('base','dataClass',dataClass); 
                            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PopulateGUI(app)
            VersionData = ver;
            if (strcmp(VersionData(1).Release,'(R2020a)') == 0 )
                waitfor(errordlg({'This version (3.08) of the GUI is only compatible with MATLAB 2020a.',...
                    'Please use the correct version of MALTAB.'},'Version Error','Understood'));
                closereq(); 
            else
                
                %questdlg({'Welcome to SPOT 3.0!','',...
                %    'Authors (v3.0): Alex Crain','Authors (v2.0): Alex Crain and Kirk Hovell',...
                %    'Authors (Legacy): Dave Rogers and Kirk Hovell',...
                %    '','Current Version: 3.08 (Public Beta)',...
                %    '','Last Edit: 2022-03-11',...
                %    '','Did you READ the Wiki? Please do: https://github.com/Carleton-SRCL/SPOT/wiki'},'Important Messages','Confirm');
                     
                % Obtain a list of all .slx files present in the working directory, and populate the dropdown menu:
                MyFolderInfo = dir([cd '\*.slx']);
                FileNames    = {MyFolderInfo.name};
                app.AvailableDiagramsDropDown.Items = FileNames;
                
                % Add duration of experiment to GUI:
                app.TotalTimeEditField.Value = evalin('base','tsim');
                
                % Initialize the components of the GUI:
                xLength = 3.51155;  %[m]
                yLength = 2.41935;  %[m]
                            
                % Flip the axis to match the table:
                axis(app.ExperimentPreview,"ij");
                
                % Set the remaining axes properties:
                app.ExperimentPreview.XAxisLocation = "top";
                app.ExperimentPreview.XLim          = [-0.65 3];
                app.ExperimentPreview.YLim          = [-0.5 4];
                
                % Plot the table:
                line(app.ExperimentPreview,[0 yLength],[0 0],"Color","black","LineWidth",2);
                hold(app.ExperimentPreview,"on");
                line(app.ExperimentPreview,[yLength yLength],[0 xLength],"Color","black","LineWidth",2);
                line(app.ExperimentPreview,[yLength 0],[xLength xLength],"Color","black","LineWidth",2);
                line(app.ExperimentPreview,[0 0],[xLength 0],"Color","black","LineWidth",2);
                grid(app.ExperimentPreview,"on");
                
                % Assign the default value to be 0; this only changes temporarily when the
                % user clicks on the Run Simulation button.
                assignin("base","simMode",1);
                
                % Populate the platform parameters:
                model_param = evalin("base","model_param");
                baseRate = evalin("base","baseRate");
                
                app.SampleRateEditField.Value = 1/baseRate;
                
                app.UITable.ColumnEditable = true;
                app.UITable.FontSize = 12;
                app.UITable.RowName = {'Base-RED';'Base-BLACK';'Base-BLUE';...
                                       'Prox.-RED';'Dist.-RED';'End-E.-RED'};
                app.UITable.ColumnName = {'Mass [Kg]','Inertia [Kg.m**2]'};
                app.UITable.Data = [model_param(1),model_param(2);...
                                    model_param(3),model_param(4);...
                                    model_param(5),model_param(6);...
                                    model_param(15),model_param(18);...
                                    model_param(16),model_param(19);...
                                    model_param(17),model_param(20)];
                app.UITable.ColumnFormat = {'char','char'};                
                               
                app.UITableGeometry.ColumnEditable = true;
                app.UITableGeometry.FontSize = 12;
                app.UITableGeometry.RowName = {'Base-RED';'Prox.-RED';'Dist.-RED';'End-E.-RED'};
                app.UITableGeometry.ColumnName = {'Joint/CG [m]','CG/Joint [m]'};
                app.UITableGeometry.Data = [NaN,model_param(8);...
                                    model_param(9),model_param(10);...
                                    model_param(11),model_param(12);...
                                    model_param(13),model_param(14)];
                app.UITableGeometry.ColumnFormat = {'char','char'};
                                
                app.UITableArmAngle.ColumnEditable = true;
                app.UITableArmAngle.FontSize = 12;
                app.UITableArmAngle.RowName = {'Phi'};
                app.UITableArmAngle.ColumnName = {'CG/Joint [rad]'};
                app.UITableArmAngle.Data = model_param(7); 
                app.UITableArmAngle.ColumnFormat = {'char'};
                
                % Check if the SLX file currently set in the dropdown is
                % open
                
                FileName = app.AvailableDiagramsDropDown.Value;
                is_open = isSLXOpen(app,string(FileName));
                
                if is_open == true
                    set(app.ConfirmOptionsButton,'enable','on');
                else
                    set(app.ConfirmOptionsButton,'enable','off');
                end
                
                
                % Set appropriate enables and disables:
                set(app.PlayAnimationButton,'enable','off');
                set(app.StopAnimationButton,'enable','off');
                set(app.SaveSimulationButton,'enable','off');
                set(app.CreateVideoCheckBox,'enable','off');
                set(app.RunSimulationButton,'enable','off');
                set(app.DeployCodeREDButton,'enable','off');
                set(app.CopyCodeToPlatformsButton,'enable','off');
                set(app.StartExperimentButton,'enable','off');
                set(app.StopExperimentButton,'enable','off');
                set(app.AnimationSpeedSlider,'enable','off');
                set(app.RealTimeTelemetryCheckBox,'enable','off');
                set(app.Slider,'enable','off');
            end
        end

        % Button pushed function: ConfirmOptionsButton
        function ConfirmOptionsButtonPushed(app, event)
            % Based on user selection in the GUI, assign variables to the workspace:
            % First, load in the selections:
            RED         = app.REDCheckBox.Value;
            BLACK       = app.BLACKCheckBox.Value;
            BLUE        = app.BLUECheckBox.Value;
            ARM         = app.ARMCheckBox.Value;
            ErrorMsg    = 0;
            
            FileName = app.AvailableDiagramsDropDown.Value;
            %Simulink.BlockDiagram.loadActiveConfigSet(FileName(1:end-4), 'DefaultConfiguration_Reduced.m');

            cs = getConfigSet(FileName(1:end-4),'Configuration');
            cs.set_param('Name', 'Configuration'); % Name
            cs.set_param('Description', ''); % Description
            cs_componentCC = CoderTarget.SettingsController;
            cs.attachComponent(cs_componentCC);
            cs.set_param('CoderTargetData', struct('UseCoderTarget',true,'TargetHardware','Raspberry Pi','ConnectionInfo',struct('XCPonTCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false),'TCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false)),'ExtMode',struct('Configuration','XCP on TCP/IP','signalBufferSize',1000000),'RTOS','Linux','RTOSBaseRateTaskPriority','40','DetectTaskOverruns',0,'Scheduler_interrupt_source',0,'BoardParameters',struct('DeviceAddress','192.168.0.101','Username','pi','Password','raspberry','BuildDir','/home/pi/ExperimentSoftware/'),'Runtime',struct('BuildAction','Build','RunOnBoot',false),'SPI',struct('SPI0CE0BusSpeed','500','SPI0CE1BusSpeed','500'),'CAN',struct('CANBusSpeed','500','CANOscillatorFrequency','16','InterruptPin','12','AllowAllFilter',1,'Buffer0IDType','Normal','AcceptanceMask0','0','AcceptanceFilter0','255','AcceptanceFilter1','255','Buffer1IDType','Normal','AcceptanceMask1','0','AcceptanceFilter2','255','AcceptanceFilter3','255','AcceptanceFilter4','255','AcceptanceFilter5','255'),'MQTT',struct('BrokerAddress','mqtt.thingspeak.com','Username','','Password','','ClientID',''),'SimulinkIO',struct('Enable_SimulinkIO',1,'TransportLayerType','TCP/IP','ModelTransportDataFcn','codertarget.raspi.ioclient.getModelTransportInfo','ServerDeployFcn','codertarget.raspi.ioclient.deployServer'),'DataVersion','2016.02'));   % CoderTargetData

            % Assign the results to the platformSelection variable:        
            if RED == 1 && BLACK == 0 && BLUE == 0 && ARM == 0
                assignin("base","platformSelection",1);     % RED
            elseif RED == 0 && BLACK == 1 && BLUE == 0 && ARM == 0
                assignin("base","platformSelection",2);     % BLACK
            elseif RED == 1 && BLACK == 1 && BLUE == 0 && ARM == 0
                assignin("base","platformSelection",3);     % RED + BLACK
            elseif RED == 1 && BLACK == 1 && BLUE == 0 && ARM == 1
                assignin("base","platformSelection",4);     % RED + BLACK + ARM
            elseif RED == 1 && BLACK == 0 && BLUE == 0 && ARM == 1
                assignin("base","platformSelection",5);     % RED + ARM
            elseif RED == 0 && BLACK == 0 && BLUE == 1 && ARM == 0
                assignin("base","platformSelection",6);     % BLUE
            elseif RED == 1 && BLACK == 0 && BLUE == 1 && ARM == 0
                assignin("base","platformSelection",7);     % BLUE + RED
            elseif RED == 1 && BLACK == 1 && BLUE == 1 && ARM == 0
                assignin("base","platformSelection",8);     % BLUE + RED + BLACK
            elseif RED == 0 && BLACK == 1 && BLUE == 1 && ARM == 0
                assignin("base","platformSelection",9);     % BLUE + BLACK
            elseif RED == 1 && BLACK == 0 && BLUE == 1 && ARM == 1
                assignin("base","platformSelection",10);    % BLUE + RED + ARM
            elseif RED == 1 && BLACK == 1 && BLUE == 1 && ARM == 1
                assignin("base","platformSelection",11);    % BLUE + RED + BLACK + ARM
            elseif RED == 0 && BLACK == 0 && BLUE == 0 && ARM == 0
                ed = errordlg("You need to select at least one platform.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                ErrorMsg = 1;
            else
                ed = errordlg("The combination of platforms selected has not been implemented yet.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                ErrorMsg = 1;
            end
            
            if (ErrorMsg~=1)                         
                AddPlatform(app,evalin("base","platformSelection"));
                set(app.RunSimulationButton,'enable','on');
                set(app.DeployCodeREDButton,'enable','on');
                set(app.CopyCodeToPlatformsButton,'enable','on');
                set(app.StartExperimentButton,'enable','on');
                set(app.StopExperimentButton,'enable','on');      
                %set(app.RealTimeTelemetryCheckBox,'enable','on');
            end
           
        end

        % Button pushed function: LoadSelectedDiagramButton
        function LoadSelectedDiagramButtonPushed(app, event)
            % When the button is pressed, open the diagram currently selected in the dropdown menu:
            FileName = app.AvailableDiagramsDropDown.Value;
            set(app.ConfirmOptionsButton,'enable','on');
            open(FileName);                        
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
        % Get the selected files from the drop down menu:
        selectedFile = get(app.AvailableDiagramsDropDown,'Value');
        
        if (bdIsLoaded(selectedFile(1:(end-4)))==1)
        
            % Set workspace parameters in case the user has changed them in the GUI:
            model_param(1)                 = app.UITable.Data(1,1);
            model_param(2)                 = app.UITable.Data(1,2);
            model_param(3)                 = app.UITable.Data(2,1);
            model_param(4)                 = app.UITable.Data(2,2);
            model_param(5)                 = app.UITable.Data(3,1);
            model_param(6)                 = app.UITable.Data(3,2);
            model_param(15)                = app.UITable.Data(4,1);
            model_param(18)                = app.UITable.Data(4,2);
            model_param(16)                = app.UITable.Data(5,1);
            model_param(19)                = app.UITable.Data(5,2);
            model_param(17)                = app.UITable.Data(6,1);
            model_param(20)                = app.UITable.Data(6,2);
            
            model_param(8)                 = app.UITableGeometry.Data(1,2);
            model_param(9)                 = app.UITableGeometry.Data(2,1);
            model_param(10)                = app.UITableGeometry.Data(2,2);
            model_param(11)                = app.UITableGeometry.Data(3,1);
            model_param(12)                = app.UITableGeometry.Data(3,2);
            model_param(13)                = app.UITableGeometry.Data(4,1);
            model_param(14)                = app.UITableGeometry.Data(4,2);           
         
            model_param(7)                = app.UITableArmAngle.Data(1,1); 
            
            assignin('base','model_param',model_param);
            assignin('base','baseRate',1/app.SampleRateEditField.Value);

            % Set the simulation mode in Simulink:
            set_param(selectedFile(1:(end-4)),'SimulationMode','normal');
        
            % Disable hardware mode:
            activeConfigObj = getActiveConfigSet(selectedFile(1:(end-4)));
            set_param(activeConfigObj,'HardwareBoard','None');
            
            % Set the simMode to 1 to indicate that a simulation is being
            % performed:
            assignin('base','simMode',1);
            assignin('base','WhoAmI',0);
                
            % Open a dialog indicating tht a simulation is being performed:
            simrun = dialog('Position',[300 300 250 150],'Name','Running Simulation...');
            
            uicontrol('Parent',simrun,...
                           'Style','text',...
                           'Position',[20 80 210 40],...
                           'String','Wait for simulation to complete.');   
                       
            options = simset('SrcWorkspace','base','DstWorkspace','base');
            if strcmp(get_param(selectedFile(1:(end-4)),'Dirty'),'on')
            	save_system(selectedFile(1:(end-4)));
            end
            sim(selectedFile(1:(end-4)),[],options)
            fprintf('Done.\n');
            set_param(activeConfigObj,'HardwareBoard','Raspberry Pi');
            close(simrun);
            
            assignin('base','simMode',0);
                
            set(app.PlayAnimationButton,'enable','on');
            set(app.StopAnimationButton,'enable','on');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.SaveSimulationButton,'enable','on');
            set(app.AnimationSpeedSlider,'enable','on');
            
            dataPacket = evalin("base","dataPacket");
            %app.ApplyDataDictionary(dataPacket); 
            
        else
            ed = errordlg("The selected Simulink diagram has not been loaded.","Error");
            set(ed, "WindowStyle", "modal");
            uiwait(ed);            
        end
        
        end

        % Button pushed function: PlayAnimationButton
        function PlayAnimationButtonPushed(app, event)
            W = evalin("base","whos"); 
            doesPacketExist = ismember('dataPacket',[W(:).name]);
            model_param = evalin("base","model_param");
            
            if app.CreateVideoCheckBox.Value == 1
                v = VideoWriter('SimulationVideo.avi','Motion JPEG AVI');
                v.Quality = 95;
                open(v);
            end

            
            if (sum(doesPacketExist) == 10)

                AddPlatform(app,evalin("base","platformSelection")); 
                
                % Print time text:
                txtT = '';
                text(app.ExperimentPreview,0.1,3.75,txtT); 
                
                % Load the data packet into the GUI workspace:
                dataPacketUp = evalin("base","dataPacket");
                
                % Downsample the data to reduce the number of points:
                [UniqueTime, UniqueIndex, ~] = unique(dataPacketUp(:,1));

                NewTimeSlow = linspace(0,UniqueTime(end),UniqueTime(end)*(app.AnimationSpeedSlider.Value/10+1))';

                dataPacket = zeros(length(NewTimeSlow),size(dataPacketUp,2));
                
                for j = 1:size(dataPacketUp,2)
                    dataPacket(:,j) = interp1(UniqueTime,dataPacketUp(UniqueIndex,j),NewTimeSlow);       
                end
                
                app.DataPacketUp = dataPacket;
                                
                for i = 1:size(dataPacket,1)
                               
                    a = dataPacket(i,6);
                    b = dataPacket(i,5);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataPacket(i,7);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.REDFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_RED = [  cos(theta) sin(theta)
                                     -sin(theta) cos(theta) ];
                                 
                        Vec_RED  = C_bI_RED*[ 0.15
                                              0.0 ];
                                                      
                        app.REDFigLine.XData = [a,a+Vec_RED(2)]; 
                        app.REDFigLine.YData = [b,b+Vec_RED(1)];
                        app.REDFigHdl.XData = P(1,:)+a;
                        app.REDFigHdl.YData = P(2,:)+b;
                    end
                    
                    x0 = dataPacket(i,6);
                    y0 = dataPacket(i,5);
                    q0 = -dataPacket(i,7);
                      
                    q1 = -(pi/2+dataPacket(i,65));
                    q2 = -dataPacket(i,66);
                    q3 = -(dataPacket(i,67));        
                    phi = pi/2-model_param(7);
                    b0  = model_param(8);
                    a1  = model_param(9);
                    b1  = model_param(10);              
                    a2  = model_param(11);                
                    b2  = model_param(12);               
                    a3  = model_param(13);  
                    b3  = model_param(14);  
                    
                    rs                      = [ x0 + (b0)*cos(phi + q0),y0 + (b0)*sin(phi + q0) ];                        
                                
                    re                      = [ x0 + (b0)*cos(phi + q0) + (a1+b1)*cos(pi/2 + q0 + q1),y0 + (b0)*sin(phi + q0) + (a1+b1)*sin(pi/2 + q0 + q1) ];
        
                    rw                      = [ x0 + (b0)*cos(phi + q0) + (a1+b1)*cos(pi/2 + q0 + q1) + (a2+b2)*cos(pi/2 + q0 + q1 + q2),y0 + (b0)*sin(phi + q0) + (a1+b1)*sin(pi/2 + q0 + q1) + (a2+b2)*sin(pi/2 + q0 + q1 + q2) ];
        
                    ree                     = [ x0 + (b0)*cos(phi + q0) + (a1+b1)*cos(pi/2 + q0 + q1) + (a2+b2)*cos(pi/2 + q0 + q1 + q2) + (a3+b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (b0)*sin(phi + q0) + (a1+b1)*sin(pi/2 + q0 + q1) + (a2+b2)*sin(pi/2 + q0 + q1 + q2) + (a3+b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                    
                    if (isempty(app.REDFigLink1) ~= 1) 
                        
                        app.REDFigLink1.XData = [rs(1,1)  re(1,1)];
                        app.REDFigLink1.YData = [rs(1,2)  re(1,2)];
                        app.REDFigLink2.XData = [re(1,1)  rw(1,1)];
                        app.REDFigLink2.YData = [re(1,2)  rw(1,2)];
                        app.REDFigLink3.XData = [rw(1,1)  ree(1,1)];
                        app.REDFigLink3.YData = [rw(1,2)  ree(1,2)];
                        app.REDFigJoin0.XData = rs(1,1);
                        app.REDFigJoin0.YData = rs(1,2);
                        app.REDFigJoin1.XData = re(1,1);
                        app.REDFigJoin1.YData = re(1,2);
                        app.REDFigJoin2.XData = rw(1,1);
                        app.REDFigJoin2.YData = rw(1,2);
                        app.REDFigJoin3.XData = ree(1,1);
                        app.REDFigJoin3.YData = ree(1,2);
                                                
                    end
                    
                    a = dataPacket(i,26);
                    b = dataPacket(i,25);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataPacket(i,27);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.BLACKFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_BLACK = [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];
                                 
                        Vec_BLACK  = C_bI_BLACK*[ 0.15
                                                  0.0 ];
                                                      
                        app.BLACKFigLine.XData = [a,a+Vec_BLACK(2)]; 
                        app.BLACKFigLine.YData = [b,b+Vec_BLACK(1)];
                        app.BLACKFigHdl.XData = P(1,:)+a;
                        app.BLACKFigHdl.YData = P(2,:)+b;
                        
                    end
                    
                    a = dataPacket(i,46);
                    b = dataPacket(i,45);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataPacket(i,47);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.BLUEFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_BLUE =  [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];
                                 
                        Vec_BLUE  = C_bI_BLUE*[ 0.15
                                                0.0 ];
                                                      
                        app.BLUEFigLine.XData = [a,a+Vec_BLUE(2)]; 
                        app.BLUEFigLine.YData = [b,b+Vec_BLUE(1)];  
                        app.BLUEFigHdl.XData = P(1,:)+a;
                        app.BLUEFigHdl.YData = P(2,:)+b;
                        
                    end                    
          
                    app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(dataPacket(i,1)),' s');
                    
                    if (app.StopAnimationButton.Value == 1)
                        app.StopAnimationButton.Value = 0;
                            if app.CreateVideoCheckBox.Value == 1
                                close(v);
                            end
                        break;
                    end
                    
                    pause(0.01)
                    drawnow limitrate
                    
                    if app.CreateVideoCheckBox.Value == 1

                           frame = getframe(app.ExperimentPreview);
                           writeVideo(v,frame);
                    end

                    
                end
                
                set(app.Slider,'enable','on');
                   
            else
                
                ed = errordlg("The variable dataPacket does not exist in the base workspace. Check that the simulation has been run, or ensure the variable is loaded.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                      
            end

            if app.CreateVideoCheckBox.Value == 1
                close(v);
            end
                       
        end

        % Callback function
        function StopAnimationButtonPushed(app, event)
            app.Interruptor = 1;
        end

        % Callback function
        function MassREDEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Callback function
        function InertiaREDEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Callback function
        function MassBLACKEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Callback function
        function InertiaBLACKEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Callback function
        function MassBLUEEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');  
        end

        % Callback function
        function InertiaBLUEEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            % Set appropriate enables and disables:
            set(app.PlayAnimationButton,'enable','off');
            set(app.StopAnimationButton,'enable','off');
            set(app.CreateVideoCheckBox,'enable','off');
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');             
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Button pushed function: REDPucksOnButton
        function REDPucksOnButtonPushed(app, event)
            fprintf('Checking connection to RED platform...\n')
            [~,results] = system('ping 192.168.0.100 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Trying to run FloatPlatform.elf...\n');
                    
                RED         = raspberrypi('192.168.0.100','pi','raspberry');
                
                if isModelRunning(RED,'FloatPlatform') == true
                    waitfor(warndlg('Warning: RED platform is already floating! Button press has been ignored.','Float Already Running'));   
                    return;
                end

                putFile(RED,[cd '\Resources\FloatPlatform.elf'],'/home/pi/ExperimentSoftware/FloatPlatform.elf');
                system(RED,'sudo chmod -R 755 ExperimentSoftware');
    
                response    = system(RED,'ls /home/pi/ExperimentSoftware/');
                errcheckExp = contains(response,'FloatPlatform.elf');
                
                if errcheckExp == 0
                    
                    waitfor(warndlg('Could not locate FloatPlatform.elf on RED. Please check that it was copied correctly from the ground station','Could not Find Diagram')); 
                
                else 
                     
                        
                    app.CustomRunModel(RED, 'FloatPlatform')
                    
                    while isModelRunning(RED,'FloatPlatform') == 0
                        
                        
                    end
                    %app.CustomRunModel(RED, 'FloatPlatform', '/home/pi/ExperimentSoftware/')      

            
                end
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg('There was a problem connecting to RED, could not start floating. Check that the IP address for RED is still accessible using !ping 192.168.0.100 in the MATLAB Command Window.','Connection Failed'));
            end
        end

        % Button pushed function: REDPucksOffButton
        function REDPucksOffButtonPushed(app, event)
             fprintf('Checking connection to RED platform...\n')
            [~,results] = system('ping 192.168.0.100 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Stopping FloatPlatform.elf...\n');
                    
                RED = raspberrypi('192.168.0.100','pi','raspberry');
                if isModelRunning(RED,'FloatPlatform') == false
                    waitfor(warndlg('Warning: FloatPlatform.elf is not currently running on RED! Button press has been ignored.','Float Not Running'));   
                else                
                    app.CustomStopModel(RED,'FloatPlatform');   
                end
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg('There was a problem connecting to RED, could not stop floating. Check that the IP address for RED is still accessible using !ping 192.168.0.100 in the MATLAB Command Window.','Connection Failed'));
            end            

        end

        % Button pushed function: BLACKPucksOnButton
        function BLACKPucksOnButtonPushed(app, event)
             
            fprintf('Checking connection to BLACK platform...\n')
            [~,results] = system('ping 192.168.0.101 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Trying to run FloatPlatform.elf...\n');
                    
                BLACK         = raspberrypi('192.168.0.101','pi','raspberry');
                
                if isModelRunning(BLACK,'FloatPlatform') == true
                    waitfor(warndlg('Warning: BLACK platform is already floating! Button press has been ignored.','Float Already Running'));   
                    return;
                end
    
                putFile(BLACK,[cd '\Resources\FloatPlatform.elf'],'/home/pi/ExperimentSoftware/FloatPlatform.elf');
                system(BLACK,'sudo chmod -R 755 ExperimentSoftware');
    
                response    = system(BLACK,'ls /home/pi/ExperimentSoftware/');
                errcheckExp = contains(response,'FloatPlatform.elf');
                
                if errcheckExp == 0
                    
                    waitfor(warndlg('Could not locate FloatPlatform.elf on RED. Please check that it was copied correctly from the ground station','Could not Find Diagram')); 
                
                else 
    
                    app.CustomRunModel(BLACK, 'FloatPlatform')
                    
                    while isModelRunning(BLACK,'FloatPlatform') == 0
                        
                        % waitng for model to start running before
                        % declaring "done"
                        
                    end                             
                
                end
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg('There was a problem connecting to BLACK, could not start floating. Check that the IP address for BLACK is still accessible using !ping 192.168.0.101 in the MATLAB Command Window.','Connection Failed'));
            end
            
        end

        % Button pushed function: BLACKPucksOffButton
        function BLACKPucksOffButtonPushed(app, event)
            
            fprintf('Checking connection to BLACK platform...\n')
            [~,results] = system('ping 192.168.0.101 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Stopping FloatPlatform.elf...\n');
                    
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                if isModelRunning(BLACK,'FloatPlatform') == false
                    waitfor(warndlg('Warning: FloatPlatform.elf is not currently running on BLACK! Button press has been ignored.','Float Not Running'));   
                else                
                    app.CustomStopModel(BLACK,'FloatPlatform');   
                end
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg('There was a problem connecting to BLACK, could not stop floating. Check that the IP address for BLACK is still accessible using !ping 192.168.0.101 in the MATLAB Command Window.','Floating Failed'));
            end  
                     
        end

        % Button pushed function: BLUEPucksOnButton
        function BLUEPucksOnButtonPushed(app, event)
         fprintf('Checking connection to BLUE platform...\n')
            [~,results] = system('ping 192.168.0.102 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Trying to run FloatPlatform.elf...\n');
                    
                BLUE         = raspberrypi('192.168.0.102','pi','raspberry');
                
                if isModelRunning(BLUE,'FloatPlatform') == true
                    waitfor(warndlg('Warning: BLUE platform is already floating! Button press has been ignored.','Float Already Running'));   
                end                
    
                putFile(BLUE,[cd '\Resources\FloatPlatform.elf'],'/home/pi/ExperimentSoftware/FloatPlatform.elf');
                system(BLUE,'sudo chmod -R 755 ExperimentSoftware');
    
                response    = system(BLUE,'ls /home/pi/ExperimentSoftware/');
                errcheckExp = contains(response,'FloatPlatform.elf');
                
                if errcheckExp == 0
                    
                    waitfor(warndlg('Could not locate FloatPlatform.elf on RED. Please check that it was copied correctly from the ground station','Could not Find Diagram')); 
                
                else 
                          
                    app.CustomRunModel(BLUE, 'FloatPlatform')  
                    
                    while isModelRunning(BLUE,'FloatPlatform') == 0
                        
                        % waitng for model to start running before
                        % declaring "done"
                        
                    end                             
                
                end
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg('There was a problem connecting to BLUE, could not start floating. Check that the IP address for BLUE is still accessible using !ping 192.168.0.102 in the MATLAB Command Window.','Connection Failed'));
            end
                  
        end

        % Button pushed function: BLUEPucksOffButton
        function BLUEPucksOffButtonPushed(app, event)
            
            fprintf('Checking connection to BLUE platform...\n')
            [~,results] = system('ping 192.168.0.102 -w 2000 -n 1');
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                
                fprintf('Connection successful! Stopping FloatPlatform.elf...\n');
                    
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                if isModelRunning(BLUE,'FloatPlatform') == false
                    waitfor(warndlg('Warning: FloatPlatform.elf is not currently running on BLUE! Button press has been ignored.','Float Not Running'));   
                else                
                    app.CustomStopModel(BLUE,'FloatPlatform');   
                end
                
                fprintf('Done!\n\n')
            else 
                waitfor(warndlg('There was a problem connecting to BLUE, could not stop floating. Check that the IP address for BLUE is still accessible using !ping 192.168.0.102 in the MATLAB Command Window.','Floating Failed'));
            end 
                  
        end

        % Button pushed function: DeployCodeREDButton
        function DeployCodeREDButtonPushed(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            %cs = getConfigSet(FileName(1:end-4),'Configuration');
            %cs.set_param('Name', 'Configuration'); % Name
            %cs.set_param('Description', ''); % Description
            %cs_componentCC = CoderTarget.SettingsController;
            %cs.attachComponent(cs_componentCC);
            %cs.set_param('CoderTargetData', struct('UseCoderTarget',true,'TargetHardware','Raspberry Pi','ConnectionInfo',struct('XCPonTCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false),'TCPIP',struct('IPAddress','codertarget.raspi.getDeviceAddress','Port','17725','Verbose',false,'RunInBackground',false)),'ExtMode',struct('Configuration','XCP on TCP/IP','signalBufferSize',1000000),'RTOS','Linux','RTOSBaseRateTaskPriority','40','DetectTaskOverruns',0,'Scheduler_interrupt_source',0,'BoardParameters',struct('DeviceAddress','192.168.0.100','Username','pi','Password','raspberry','BuildDir','/home/pi/ExperimentSoftware/'),'Runtime',struct('BuildAction','Build','RunOnBoot',false),'SPI',struct('SPI0CE0BusSpeed','500','SPI0CE1BusSpeed','500'),'CAN',struct('CANBusSpeed','500','CANOscillatorFrequency','16','InterruptPin','12','AllowAllFilter',1,'Buffer0IDType','Normal','AcceptanceMask0','0','AcceptanceFilter0','255','AcceptanceFilter1','255','Buffer1IDType','Normal','AcceptanceMask1','0','AcceptanceFilter2','255','AcceptanceFilter3','255','AcceptanceFilter4','255','AcceptanceFilter5','255'),'MQTT',struct('BrokerAddress','mqtt.thingspeak.com','Username','','Password','','ClientID',''),'SimulinkIO',struct('Enable_SimulinkIO',1,'TransportLayerType','TCP/IP','ModelTransportDataFcn','codertarget.raspi.ioclient.getModelTransportInfo','ServerDeployFcn','codertarget.raspi.ioclient.deployServer'),'DataVersion','2016.02'));   % CoderTargetData

            answer = questdlg('Warning: Check which "Deploy" option is currently selected under the "Hardware" simulink tab. It should automatically change to "Build" when this warning is dismissed. If "Build, Deploy, and Run" is selected, the experiment will start immediately after building!','Deployment Warning','Understood','Cancel','Cancel');
            
            if answer == "Cancel"
                
            else
                assignin('base','simMode',0);

                FileName = app.AvailableDiagramsDropDown.Value;
                
                fprintf('Checking connection to Ground Station...\n')
                [~,results] = system(['ping 192.168.0.106 -w 2000 -n 1']);
                
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0

                    GS = raspberrypi('192.168.0.106','pi','raspberry'); %#ok<NASGU> 
                    if strcmp(get_param(FileName(1:(end-4)),'Dirty'),'on')
                        save_system(FileName(1:(end-4)));
                    end
                    slbuild(FileName(1:(end-4)));
                    
                else 
                    
                    fprintf('There was a problem connecting to the Ground Station, build stopped.\n')
                    
                end
              
                assignin('base','simMode',1);
            end
            
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            FileName = app.AvailableDiagramsDropDown.Value;
            
            % Initialize the PS
            
%             fprintf('Checking connection to ground station...\n')
%             [~,results] = system('ping 192.168.0.106 -w 2000 -n 1');
%             if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
%                 
%                 fprintf('Connection successful!\n');
%                     
%                 GS         = raspberrypi('192.168.0.106','pi','raspberry');
%                 
%                                               
%                 if isModelRunning(GS,'PhaseSpace_INIT_lib') == true
%                     waitfor(warndlg('Warning: PhaseSpace is already running on the ground station! Stopping previous model....','Model Already Running'));
%                 end
%     
%                 putFile(GS,[cd '\Resources\PhaseSpace_INIT_lib.elf'],'/home/pi/ExperimentSoftware/PhaseSpace_INIT_lib.elf');
%                 system(GS,'sudo chmod -R 755 ExperimentSoftware');
%     
%                 response    = system(GS,'ls /home/pi/ExperimentSoftware/');
%                 errcheckExp = contains(response,'PhaseSpace_INIT_lib.elf');
%                 
%                 if errcheckExp == 0
%                     
%                     waitfor(warndlg('Could not locate PhaseSpace_INIT_lib.elf on ground station. Please check that it was compiled and that the compiled diagram was placed in the Resources folder.','Could not Find Diagram'));
%                 
%                 else 
% 
%                     app.CustomRunModel(GS, 'PhaseSpace_INIT_lib')      
%            
%                 end
%                 
%                 fprintf('Done!\n\n')
%             else 
%                 waitfor(warndlg('There was a problem connecting to the ground station, could not start experiment. Check that the IP address for the ground staiton is still accessible using !ping 192.168.0.106 in the MATLAB Command Window.','Connection Failed'));
%             end
   
            if app.REDCheckBox.Value == 1
            
                fprintf('Checking connection to RED platform...\n')
                [~,results] = system('ping 192.168.0.100 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    
                    fprintf('Connection successful!\n');
                        
                    RED         = raspberrypi('192.168.0.100','pi','raspberry');                    
                                                  
                    if isModelRunning(RED,FileName(1:(end-4))) == true
                        waitfor(warndlg('Warning: The model you have selected is already running on RED! Stopping previous model....','Model Already Running'));
                        app.CustomStopModel(RED,FileName(1:(end-4)))
                    end
                    
                    putFile(RED,[cd '\' FileName(1:(end-4)) '.elf'],['/home/pi/ExperimentSoftware/' FileName(1:(end-4)) '.elf']);
                    system(RED,'sudo chmod -R 755 ExperimentSoftware');
        
                    response    = system(RED,'ls /home/pi/ExperimentSoftware/');
                    errcheckExp = contains(response,[FileName(1:(end-4)) '.elf']);
                    
                    if errcheckExp == 0
                        
                        waitfor(warndlg(['Could not locate ' FileName(1:(end-4)) '.elf on RED. Please check that it was copied correctly from the ground station.'],'Could not Find Diagram'));
                        FailedFlag = true;
                    
                    else 

                        app.CustomRunModel(RED, FileName(1:(end-4)))  
                        FailedFlag = false;
  
                    end
                    
                    fprintf('Done!\n\n')
                else 
                    waitfor(warndlg('There was a problem connecting to RED, could not start experiment. Check that the IP address for RED is still accessible using !ping 192.168.0.100 in the MATLAB Command Window.','Connection Failed'));
                    FailedFlag = true;
                end        
                
            end

            if app.BLACKCheckBox.Value == 1
            
                fprintf('Checking connection to BLACK platform...\n')
                [~,results] = system('ping 192.168.0.101 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    
                    fprintf('Connection successful!\n');
                        
                    BLACK         = raspberrypi('192.168.0.101','pi','raspberry');
                    
                    if isModelRunning(BLACK,FileName(1:(end-4))) == true
                        waitfor(warndlg('Warning: The model you have selected is already running on BLACK! Stopping previous model....','Model Already Running'));
                        app.CustomStopModel(BLACK,FileName(1:(end-4)))
                    end
                    
                    putFile(BLACK,[cd '\' FileName(1:(end-4)) '.elf'],['/home/pi/ExperimentSoftware/' FileName(1:(end-4)) '.elf']);
                    system(BLACK,'sudo chmod -R 755 ExperimentSoftware');
        
                    response    = system(BLACK,'ls /home/pi/ExperimentSoftware/');
                    errcheckExp = contains(response,[FileName(1:(end-4)) '.elf']);
                    
                    if errcheckExp == 0
                        
                        waitfor(warndlg(['Could not locate ' FileName(1:(end-4)) '.elf on BLACK. Please check that it was copied correctly from the ground station.'],'Could not Find Diagram'));
                        FailedFlag = true;
                    
                    else 
                              
                        app.CustomRunModel(BLACK, FileName(1:(end-4)))    
                        FailedFlag = false;

                    end
                    
                    fprintf('Done!\n\n')
                else 
                    waitfor(warndlg('There was a problem connecting to BLACK, could not start experiment. Check that the IP address for BLACK is still accessible using !ping 192.168.0.101 in the MATLAB Command Window.','Connection Failed'));
                    FailedFlag = true;
                end        
                
            end            

            if app.BLUECheckBox.Value == 1
            
                fprintf('Checking connection to BLUE platform...\n')
                [~,results] = system('ping 192.168.0.102 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    
                    fprintf('Connection successful!\n');
                        
                    BLUE         = raspberrypi('192.168.0.102','pi','raspberry');
        
                                                  
                    if isModelRunning(BLUE,FileName(1:(end-4))) == true
                        waitfor(warndlg('Warning: The model you have selected is already running on BLACK! Stopping previous model....','Model Already Running'));
                        app.CustomStopModel(RED,FileName(1:(end-4)))
                    end

                    putFile(BLUE,[cd '\' FileName(1:(end-4)) '.elf'],['/home/pi/ExperimentSoftware/' FileName(1:(end-4)) '.elf']);
                    system(BLUE,'sudo chmod -R 755 ExperimentSoftware');
        
                    response    = system(BLUE,'ls /home/pi/ExperimentSoftware/');
                    errcheckExp = contains(response,[FileName(1:(end-4)) '.elf']);
                    
                    if errcheckExp == 0
                        
                        waitfor(warndlg(['Could not locate ' FileName(1:(end-4)) '.elf on BLUE. Please check that it was copied correctly from the ground station.'],'Could not Find Diagram'));
                        FailedFlag = true;
                    
                    else 

                        app.CustomRunModel(BLUE, FileName(1:(end-4)))    
                        FailedFlag = false;

                    
                    end
                    
                    fprintf('Done!\n\n')
                else 
                    waitfor(warndlg('There was a problem connecting to BLUE, could not start experiment. Check that the IP address for BLUE is still accessible using !ping 192.168.0.102 in the MATLAB Command Window.','Connection Failed'));
                    FailedFlag = true;
                end        
                
            end


            if FailedFlag == false
                
                baseRate = evalin("base","baseRate");
                
                app.CustomStartCameras(round((1/baseRate)*0.8));
                
%                 fprintf('Checking connection to ground station...\n')
%                 [~,results] = system('ping 192.168.0.106 -w 2000 -n 1');
%                 if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
%                     
%                     fprintf('Connection successful!\n');
%     
%                     GS         = raspberrypi('192.168.0.106','pi','raspberry');
%                          
%                     if isModelRunning(GS,'PhaseSpace_INIT_lib') == true
%                         app.CustomStopModel(GS, 'PhaseSpace_INIT_lib'); 
%                     else
%                         waitfor(warndlg('Warning: PhaseSpace is not running on the ground station! Button press has been ignored.','Model Already Running'));     
%                     end
%        
%                     fprintf('Done!\n\n')
%                 else 
%                     waitfor(warndlg('There was a problem connecting to the ground station, could not stop experiment. Check that the IP address for the ground staiton is still accessible using !ping 192.168.0.106 in the MATLAB Command Window.','Connection Failed'));
%                 end   
                
                
            end

        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            
            FileName = app.AvailableDiagramsDropDown.Value;
            
%             fprintf('Checking connection to ground station...\n')
%             [~,results] = system('ping 192.168.0.106 -w 2000 -n 1');
%             if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
%                 
%                 fprintf('Connection successful!\n');
% 
%                 GS         = raspberrypi('192.168.0.106','pi','raspberry');
%                      
%                 if isModelRunning(GS,'PhaseSpace_INIT_lib') == true
%                     app.CustomStopModel(GS, 'PhaseSpace_INIT_lib'); 
%                 else
%                     waitfor(warndlg('Warning: PhaseSpace is not running on the ground station! Button press has been ignored.','Model Already Running'));     
%                 end
%    
%                 fprintf('Done!\n\n')
%             else 
%                 waitfor(warndlg('There was a problem connecting to the ground station, could not stop experiment. Check that the IP address for the ground staiton is still accessible using !ping 192.168.0.106 in the MATLAB Command Window.','Connection Failed'));
%             end     
            
            if app.REDCheckBox.Value == 1
            
                fprintf('Checking connection to RED platform...\n')
                [~,results] = system('ping 192.168.0.100 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    
                    fprintf('Connection successful!\n');
                        
                    RED         = raspberrypi('192.168.0.100','pi','raspberry');
                    
                    app.CustomStopModel(RED, 'CleanShutdown') 
                    putFile(RED,[cd '\Resources\CleanShutdown.elf'],'/home/pi/ExperimentSoftware/CleanShutdown.elf');
                    system(RED,'sudo chmod -R 755 ExperimentSoftware');
        
                    response    = system(RED,'ls /home/pi/ExperimentSoftware/');
                    errcheckExp = contains(response,'CleanShutdown.elf');
                    
                    if errcheckExp == 0
                        
                        waitfor(warndlg('Could not locate CleanShutdown.elf on RED. Please check that it is present in the Resources directory.','Could not Find Diagram'));
                    
                    else 
                              
                        if isModelRunning(RED,FileName(1:(end-4))) == false
                            waitfor(warndlg('Warning: The model you have selected is not running on RED! Button press has been ignored.','Model Already Running'));
                            
                        else
                            app.CustomStopModel(RED, FileName(1:(end-4)));
                            app.CustomRunModel(RED, 'CleanShutdown')
                            app.CustomStopModel(RED, 'CleanShutdown')
                        end
                    
                    end
                    
                    fprintf('Done!\n\n')
                else 
                    waitfor(warndlg('There was a problem connecting to RED, could not stop experiment. Check that the IP address for RED is still accessible using !ping 192.168.0.100 in the MATLAB Command Window.','Connection Failed'));
                end        
                
            end

            if app.BLACKCheckBox.Value == 1
                
                fprintf('Checking connection to BLACK platform...\n')
                [~,results] = system('ping 192.168.0.101 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    
                    fprintf('Connection successful!\n');
                        
                    BLACK         = raspberrypi('192.168.0.101','pi','raspberry');
                    app.CustomStopModel(BLACK, 'CleanShutdown')
                    putFile(BLACK,[cd '\Resources\CleanShutdown.elf'],'/home/pi/ExperimentSoftware/CleanShutdown.elf');
                    system(BLACK,'sudo chmod -R 755 ExperimentSoftware');
        
                    response    = system(BLACK,'ls /home/pi/ExperimentSoftware/');
                    errcheckExp = contains(response,'CleanShutdown.elf');
                    
                    if errcheckExp == 0
                        
                        waitfor(warndlg('Could not locate CleanShutdown.elf on BLACK. Please check that it is present in the Resources directory.','Could not Find Diagram'));
                    
                    else 
                              
                        if isModelRunning(BLACK,FileName(1:(end-4))) == false
                            waitfor(warndlg('Warning: The model you have selected is not running on BLACK! Button press has been ignored.','Model Already Running'));
                            
                        else
                            app.CustomStopModel(BLACK, FileName(1:(end-4)));  
                            app.CustomRunModel(BLACK, 'CleanShutdown') 
                            app.CustomStopModel(BLACK, 'CleanShutdown') 
                            
                        end
                    
                    end
                    
                    fprintf('Done!\n\n')
                else 
                    waitfor(warndlg('There was a problem connecting to BLACK, could not stop experiment. Check that the IP address for BLACK is still accessible using !ping 192.168.0.101 in the MATLAB Command Window.','Connection Failed'));
                end         
                
            end            

            if app.BLUECheckBox.Value == 1
            
                fprintf('Checking connection to BLUE platform...\n')
                [~,results] = system('ping 192.168.0.102 -w 2000 -n 1');
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    
                    fprintf('Connection successful!\n');
                        
                    BLUE         = raspberrypi('192.168.0.102','pi','raspberry');
                    app.CustomStopModel(BLUE, 'CleanShutdown')
                    putFile(BLUE,[cd '\Resources\CleanShutdown.elf'],'/home/pi/ExperimentSoftware/CleanShutdown.elf');
                    system(BLUE,'sudo chmod -R 755 ExperimentSoftware');
        
                    response    = system(BLUE,'ls /home/pi/ExperimentSoftware/');
                    errcheckExp = contains(response,'CleanShutdown.elf');
                    
                    if errcheckExp == 0
                        
                        waitfor(warndlg('Could not locate CleanShutdown.elf on BLUE. Please check that it is present in the Resources directory.','Could not Find Diagram'));
                    
                    else 
                              
                        if isModelRunning(BLUE,FileName(1:(end-4))) == false
                            waitfor(warndlg('Warning: The model you have selected is not running on BLUE! Button press has been ignored.','Model Already Running'));
                            
                        else
                            app.CustomStopModel(BLUE, FileName(1:(end-4)));  
                            app.CustomRunModel(BLUE, 'CleanShutdown') 
                            app.CustomStopModel(BLUE, 'CleanShutdown')
                        end
                    
                    end
                    
                    fprintf('Done!\n\n')
                else 
                    waitfor(warndlg('There was a problem connecting to BLUE, could not stop experiment. Check that the IP address for BLUE is still accessible using !ping 192.168.0.102 in the MATLAB Command Window.','Connection Failed'));
                end     
                
            end
            
            app.CustomStopCameras;
            
        end

        % Button pushed function: SaveSimulationButton
        function SaveSimulationButtonPushed(app, event)
            % Grab the file name from the drop down menu:
            
            W = evalin("base","whos"); 
            doesPacketExist = ismember('dataPacket',[W(:).name]);
            
            if (sum(doesPacketExist) == 10)
                
                dataPacket     = evalin('base', 'dataPacket');
                dataClass      = evalin('base', 'dataClass');
                
                % Move the file to a folder for storage:
                
                datetime       = clock;
                
                foldername = ['SimulationData_' num2str(datetime(1)) '_' ...
                    num2str(datetime(2)) '_' num2str(datetime(3)) '_'...
                num2str(datetime(4)) '_' num2str(datetime(5))];
                
                save('dataPacket_SIM.mat','dataPacket','dataClass');
                
                mkdir([cd '\Saved Data\' foldername '\']);
                
                movefile([cd '\dataPacket_SIM.mat'],...
                [cd '\Saved Data\' foldername '\dataPacket_SIM.mat']);
                       
            else 
                
                ed = errordlg("The variable 'dataPacket' does not exist in the base workspace. Cannot save data.","Error");
                set(ed, "WindowStyle", "modal");
                uiwait(ed);
                
            end
        end

        % Button pushed function: SaveExperimentButton
        function SaveExperimentButtonPushed(app, event)
            % Grab the file name from the drop down menu:
            Choice = questdlg('Warning: The experiment must be terminated before exporting any data. If you have not ended the experiment, select "Cancel", otherwise select "Proceed".','Data Export Warning','Proceed','Cancel','Proceed');
            switch Choice
                case 'Proceed'
                    % First, load in the selections:
                    RED         = app.REDCheckBox.Value;
                    BLACK       = app.BLACKCheckBox.Value;
                    BLUE        = app.BLUECheckBox.Value;
                    
                    % Grab the diagram name:
                    FileName = app.AvailableDiagramsDropDown.Value;
                    
                    % Get dateTime:
                    datetime       = clock;
                    
                    if RED == 1
                    
                        % Open up a connection to the RED platform:
                        red              = raspberrypi('192.168.0.100', 'pi', 'raspberry');
                    
                        % Retrieve any data file ending with .mat:
                        getFile(red,['./ExpLog*']);
                    
                        % Delete the file from the RP3:                    
                        deleteFile(red,['./ExpLog*']);
                        
                        % Move the file to a folder for storage:
                        foldername_RED = ['ExperimentData_RED_' num2str(datetime(1)) '_' ...
                            num2str(datetime(2)) '_' num2str(datetime(3)) '_'...
                        num2str(datetime(4)) '_' num2str(datetime(5))];
                        
                        % Move all data to new folder:
                        movefile([cd '\ExpLog*'],[cd '\Saved Data\' foldername_RED]);
                    
                    end
                    
                    if BLACK == 1
                    
                        % Open up a connection to the RED platform:
                        black              = raspberrypi('192.168.0.101', 'pi', 'raspberry');
                    
                        % Retrieve any data file ending with .mat:
                        getFile(black,['./ExpLog*']);
                    
                        % Delete the file from the RP3:                    
                        deleteFile(black,['./ExpLog*']);
                        
                        % Move the file to a folder for storage:
                        foldername_BLACK = ['ExperimentData_BLACK_' num2str(datetime(1)) '_' ...
                            num2str(datetime(2)) '_' num2str(datetime(3)) '_'...
                        num2str(datetime(4)) '_' num2str(datetime(5))];
                        
                        % Move all data to new folder:
                        movefile([cd '\ExpLog*'],[cd '\Saved Data\' foldername_BLACK]);
                    
                    end
                    
                    if BLUE == 1
                    
                        % Open up a connection to the RED platform:
                        blue              = raspberrypi('192.168.0.102', 'pi', 'raspberry');
                    
                        % Retrieve any data file ending with .mat:
                        getFile(blue,['./ExpLog*']);
                    
                        % Delete the file from the RP3:                    
                        deleteFile(blue,['./ExpLog*']);
                        
                        % Move the file to a folder for storage:
                        foldername_BLUE = ['ExperimentData_BLUE_' num2str(datetime(1)) '_' ...
                            num2str(datetime(2)) '_' num2str(datetime(3)) '_'...
                        num2str(datetime(4)) '_' num2str(datetime(5))];
                        
                        % Move all data to new folder:
                        movefile([cd '\ExpLog*'],[cd '\Saved Data\' foldername_BLUE]);
                    
                    end
                case 'Cancel'
                    % Do nothing.
            end

            
        end

        % Button pushed function: PingREDButton
        function PingREDButtonPushed(app, event)
         
            trytime = char(datetime(now,'ConvertFrom','datenum'));

            [~,results] = system(['ping 192.168.0.100 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
            fprintf(results)
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
               RED = raspberrypi('192.168.0.100', 'pi', 'raspberry');
               app.REDConnectedLamp.Color = [0.00,1.00,0.00];
               app.REDTextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP:' RED.DeviceAddress newline 'Port:' num2str(RED.Port)];
            else 
               app.REDConnectedLamp.Color = [1.00,0.00,0.00];
               app.REDTextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end
        end

        % Button pushed function: PingBLACKButton
        function PingBLACKButtonPushed(app, event)
            trytime = char(datetime(now,'ConvertFrom','datenum'));

            [~,results] = system(['ping 192.168.0.101 -w 2000 -n '  num2str(app.BLACKPingTimeout.Value)]);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
               BLACK = raspberrypi('192.168.0.101', 'pi', 'raspberry');
               app.BLACKConnectedLamp.Color = [0.00,1.00,0.00];
               app.BLACKTextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP:' BLACK.DeviceAddress newline 'Port:' num2str(BLACK.Port)];
            else 
               app.BLACKConnectedLamp.Color = [1.00,0.00,0.00];
               app.BLACKTextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end
        end

        % Button pushed function: PingBLUEButton
        function PingBLUEButtonPushed(app, event)
            trytime = char(datetime(now,'ConvertFrom','datenum'));

            [~,results] = system(['ping 192.168.0.102 -w 2000 -n '  num2str(app.BLUEPingTimeout.Value)]);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
               BLUE = raspberrypi('192.168.0.102', 'pi', 'raspberry');
               app.BLUEConnectedLamp.Color = [0.00,1.00,0.00];
               app.BLUETextArea.Value = [trytime newline newline 'Ping Successful!' newline newline 'Device Info:' newline...
                   newline 'IP:' BLUE.DeviceAddress newline 'Port:' num2str(BLUE.Port)];
            else 
               app.BLUEConnectedLamp.Color = [1.00,0.00,0.00];
               app.BLUETextArea.Value = [trytime newline newline 'Ping Failed...'];           
            end
        end

        % Callback function
        function SoftwareInstallationMenuSelected(app, event)
            web('https://youtu.be/d77dXB41VeI','-browser' );
        end

        % Menu selected function: 
        % ConnectingtoaRaspberryPiRemotelyMenu
        function ConnectingtoaRaspberryPiRemotelyMenuSelected(app, event)
            web('https://youtu.be/1Ly6PqwiL1w','-browser' );
        end

        % Menu selected function: RemovingtheTableCoverMenu
        function RemovingtheTableCoverMenuSelected(app, event)
            web('https://youtu.be/0DmQvlUzcDc','-browser' );
        end

        % Menu selected function: RetrievingtheIsopropanolMenu
        function RetrievingtheIsopropanolMenuSelected(app, event)
            web('https://youtu.be/nVwOGg06n6c','-browser' );
        end

        % Menu selected function: TableCleaningProceduresMenu
        function TableCleaningProceduresMenuSelected(app, event)
            web('https://youtu.be/xqzN2uYfsFM','-browser' );
        end

        % Menu selected function: SavingExperimentDataMenu
        function SavingExperimentDataMenuSelected(app, event)
            web('https://youtu.be/kHlCK_5decQ','-browser' );
        end

        % Menu selected function: RemovingtheRoboticArmMenu
        function RemovingtheRoboticArmMenuSelected(app, event)
            web('https://youtu.be/KlgUwF5Puzk','-browser' );
        end

        % Button pushed function: FireREDThrustersButton
        function FireREDThrustersButtonPushed(app, event)
            RED = raspberrypi('192.168.0.100','pi','raspberry');
            if isModelRunning(RED,'ValidateThrustersRED') == true
                stopModel(RED,'ValidateThrustersRED');   
            end  
            runModel(RED,'ValidateThrustersRED');  
            waitfor(warndlg('Running thruster validation for RED. When finished, select OK to proceed.','Thruster Validation')); 
            stopModel(RED,'ValidateThrustersRED');
        end

        % Button pushed function: OpenREDCMDShell
        function OpenREDCMDShellButtonPushed(app, event)
            RED = raspberrypi('192.168.0.100','pi','raspberry');
            openShell(RED);             
        end

        % Button pushed function: FireBLACKThrustersButton
        function FireBLACKThrustersButtonPushed(app, event)
            BLACK = raspberrypi('192.168.0.101','pi','raspberry');
            if isModelRunning(BLACK,'ValidateThrustersBLACK') == true
                stopModel(BLACK,'ValidateThrustersBLACK');   
            end 
            waitfor(warndlg('Running thruster validation for BLACK. When finished, select OK to proceed.','Thruster Validation'));  
            stopModel(BLACK,'ValidateThrustersBLACK');             
        end

        % Button pushed function: OpenBLACKCMDShell
        function OpenBLACKCMDShellButtonPushed(app, event)
            BLACK = raspberrypi('192.168.0.101','pi','raspberry');
            openShell(BLACK);              
        end

        % Button pushed function: OpenBLUECMDShell
        function OpenBLUECMDShellButtonPushed(app, event)
            BLUE = raspberrypi('192.168.0.102','pi','raspberry');
            openShell(BLUE);              
        end

        % Button pushed function: FireBLUEThrustersButton
        function FireBLUEThrustersButtonPushed(app, event)
            BLUE = raspberrypi('192.168.0.102','pi','raspberry');
            if isModelRunning(BLUE,'ValidateThrustersBLUE') == true
                stopModel(BLUE,'ValidateThrustersBLUE');   
            end 
            waitfor(warndlg('Running thruster validation for BLUE. When finished, select OK to proceed.','Thruster Validation'));  
            stopModel(BLUE,'ValidateThrustersBLUE');                
        end

        % Value changed function: REDCheckBox
        function REDCheckBoxValueChanged(app, event)
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');             
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changed function: BLACKCheckBox
        function BLACKCheckBoxValueChanged(app, event)
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');             
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changed function: BLUECheckBox
        function BLUECheckBoxValueChanged(app, event)
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');             
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changed function: ARMCheckBox
        function ARMCheckBoxValueChanged(app, event)
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');             
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Menu selected function: GitHubMenu
        function GitHubMenuSelected(app, event)
            web('https://github.com/Carleton-SRCL/SPOT/wiki','-browser' );
        end

        % Menu selected function: GooglePhotosMenu
        function GooglePhotosMenuSelected(app, event)
           web('https://photos.google.com/share/AF1QipNfJT6JaXArg4jsdNeEDJND8l6JiP75N8nS3p4yscl8leAFTLd5wW7gAhIgfqgyiQ?key=SmpJNUhQNE05OGh2NUhSWXc0UmFkZnJVbHdGZmJ3','-browser' );
        end

        % Menu selected function: YoutubeChannelMenu
        function YoutubeChannelMenuSelected(app, event)
            web('https://www.youtube.com/user/SRCLabCarleton','-browser' );
        end

        % Menu selected function: 
        % RunningaSimulationandExperimentMenu
        function RunningaSimulationandExperimentMenuSelected(app, event)
            web('https://youtu.be/N2YYbz6UxEw','-browser');
        end

        % Button pushed function: ShowDefsButton
        function ShowDefsButtonPushed(app, event)
            PictureofArm = imread([cd '/Resources/ArmConfig.png']);
            fg1 = figure(1);
            ax1 = gca;
            image(ax1,PictureofArm);
            set(fg1,'Position',[1.174333333333333e+03,221,5.166666666666665e+02,562]);
            % Create textarrow
            annotation(fg1,'textarrow',[0.340645161290316 0.430967741935484],...
                [0.413928649835347 0.492864983534577],'String',{'Proximal'});
            
            % Create textarrow
            annotation(fg1,'textarrow',[0.579354838709678 0.465806451612904],...
                [0.65642151481888 0.655323819978046],'String',{'Distal'});
            
            % Create textarrow
            annotation(fg1,'textarrow',[0.323870967741936 0.423225806451614],...
                [0.771679473106476 0.772777167947311],'String',{'End-Effector'});            
        end

        % Menu selected function: ChargingaLEDBatteryPackMenu
        function ChargingaLEDBatteryPackMenuSelected(app, event)
            web('https://youtu.be/_GZzT4T4Xq8','-browser');
        end

        % Menu selected function: ChargingaPlatformMenu
        function ChargingaPlatformMenuSelected(app, event)
            web('https://youtu.be/wDpUcpgEBgA','-browser');
        end

        % Menu selected function: InstallinganAirTankMenu
        function InstallinganAirTankMenuSelected(app, event)
            web('https://youtu.be/4xL3vRoF7FE','-browser');
        end

        % Menu selected function: InstallingtheRoboticArmMenu
        function InstallingtheRoboticArmMenuSelected(app, event)
            web('https://youtu.be/ZjW5lB-b85M','-browser');
        end

        % Menu selected function: ForkingtheSPOTRepositoryMenu
        function ForkingtheSPOTRepositoryMenuSelected(app, event)
            web('https://youtu.be/Vu77edOiv6o','-browser');
        end

        % Menu selected function: PlacingtheTableCoverMenu
        function PlacingtheTableCoverMenuSelected(app, event)
            web('https://youtu.be/UVv9MAwmUHA','-browser');
        end

        % Menu selected function: FillinganAirTankMenu
        function FillinganAirTankMenuSelected(app, event)
            web('https://youtu.be/2G6YD_iBmaA','-browser');
        end

        % Menu selected function: ExportingVideoswithQCViewerMenu
        function ExportingVideoswithQCViewerMenuSelected(app, event)
            web('https://youtu.be/H8Ht0rh1U1E','-browser');
        end

        % Menu selected function: 
        % CleaningthePucksandMovingaPlatformMenu
        function CleaningthePucksandMovingaPlatformMenuSelected(app, event)
            web('https://youtu.be/eQQ0q6dXufQ','-browser');
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');             
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Cell edit callback: UITableGeometry
        function UITableGeometryCellEdit(app, event)
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');             
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off'); 
        end

        % Cell edit callback: UITableArmAngle
        function UITableArmAngleCellEdit(app, event)
            set(app.RunSimulationButton,'enable','off');
            set(app.DeployCodeREDButton,'enable','off');             
            set(app.CopyCodeToPlatformsButton,'enable','off')
            set(app.StartExperimentButton,'enable','off');
            set(app.StopExperimentButton,'enable','off');
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue(changingValue<1)=1;
            model_param = evalin("base","model_param");
            if (isempty(app.DataPacketUp)~=1)

                AddPlatform(app,evalin("base","platformSelection")); 
                
                % Print time text:
                txtT = '';
                text(app.ExperimentPreview,0.1,3.75,txtT); 
                                        
                a = app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),6);
                b = app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),5);
                w = 0.3;
                h = 0.3;
                theta = -app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),7);
                
                X = [-w/2 w/2 w/2 -w/2 -w/2];
                Y = [h/2 h/2 -h/2 -h/2 h/2];
                P = [X;Y];
                ct = cos(theta);
                st = sin(theta);
                R = [ct -st;st ct];
                P = R * P;
                
                if (isempty(app.REDFigHdl) ~= 1) 
                    
                   % Draw the line showing the direction of the front face:
                    C_bI_RED = [  cos(theta) sin(theta)
                                 -sin(theta) cos(theta) ];
                             
                    Vec_RED  = C_bI_RED*[ 0.15
                                          0.0 ];
                                                  
                    app.REDFigLine.XData = [a,a+Vec_RED(2)]; 
                    app.REDFigLine.YData = [b,b+Vec_RED(1)];
                    app.REDFigHdl.XData = P(1,:)+a;
                    app.REDFigHdl.YData = P(2,:)+b;
                end
                
                x0 = app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),6);
                y0 = app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),5);
                q0 = -app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),7);
                  
                q1 = -(pi/2+app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),65));
                q2 = -app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),66);
                q3 = -(app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),67));        
                phi = pi/2-model_param(7);
                b0  = model_param(8);
                a1  = model_param(9);
                b1  = model_param(10);              
                a2  = model_param(11);                
                b2  = model_param(12);               
                a3  = model_param(13);  
                b3  = model_param(14);  
                
                rs                      = [ x0 + (b0)*cos(phi + q0),y0 + (b0)*sin(phi + q0) ];                        
                            
                re                      = [ x0 + (b0)*cos(phi + q0) + (a1+b1)*cos(pi/2 + q0 + q1),y0 + (b0)*sin(phi + q0) + (a1+b1)*sin(pi/2 + q0 + q1) ];
    
                rw                      = [ x0 + (b0)*cos(phi + q0) + (a1+b1)*cos(pi/2 + q0 + q1) + (a2+b2)*cos(pi/2 + q0 + q1 + q2),y0 + (b0)*sin(phi + q0) + (a1+b1)*sin(pi/2 + q0 + q1) + (a2+b2)*sin(pi/2 + q0 + q1 + q2) ];
    
                ree                     = [ x0 + (b0)*cos(phi + q0) + (a1+b1)*cos(pi/2 + q0 + q1) + (a2+b2)*cos(pi/2 + q0 + q1 + q2) + (a3+b3)*cos(pi/2 + q0 + q1 + q2 + q3),y0 + (b0)*sin(phi + q0) + (a1+b1)*sin(pi/2 + q0 + q1) + (a2+b2)*sin(pi/2 + q0 + q1 + q2) + (a3+b3)*sin(pi/2 + q0 + q1 + q2 + q3)];
                
                if (isempty(app.REDFigLink1) ~= 1) 
                    
                    app.REDFigLink1.XData = [rs(1,1)  re(1,1)];
                    app.REDFigLink1.YData = [rs(1,2)  re(1,2)];
                    app.REDFigLink2.XData = [re(1,1)  rw(1,1)];
                    app.REDFigLink2.YData = [re(1,2)  rw(1,2)];
                    app.REDFigLink3.XData = [rw(1,1)  ree(1,1)];
                    app.REDFigLink3.YData = [rw(1,2)  ree(1,2)];
                    app.REDFigJoin0.XData = rs(1,1);
                    app.REDFigJoin0.YData = rs(1,2);
                    app.REDFigJoin1.XData = re(1,1);
                    app.REDFigJoin1.YData = re(1,2);
                    app.REDFigJoin2.XData = rw(1,1);
                    app.REDFigJoin2.YData = rw(1,2);
                    app.REDFigJoin3.XData = ree(1,1);
                    app.REDFigJoin3.YData = ree(1,2);
                                            
                end
                
                a = app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),26);
                b = app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),25);
                w = 0.3;
                h = 0.3;
                theta = -app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),27);
                
                X = [-w/2 w/2 w/2 -w/2 -w/2];
                Y = [h/2 h/2 -h/2 -h/2 h/2];
                P = [X;Y];
                ct = cos(theta);
                st = sin(theta);
                R = [ct -st;st ct];
                P = R * P;
                
                if (isempty(app.BLACKFigHdl) ~= 1) 
                    
                   % Draw the line showing the direction of the front face:
                    C_bI_BLACK = [  cos(theta) sin(theta)
                                   -sin(theta) cos(theta) ];
                             
                    Vec_BLACK  = C_bI_BLACK*[ 0.15
                                              0.0 ];
                                                  
                    app.BLACKFigLine.XData = [a,a+Vec_BLACK(2)]; 
                    app.BLACKFigLine.YData = [b,b+Vec_BLACK(1)];
                    app.BLACKFigHdl.XData = P(1,:)+a;
                    app.BLACKFigHdl.YData = P(2,:)+b;
                    
                end
                
                a = app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),46);
                b = app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),45);
                w = 0.3;
                h = 0.3;
                theta = -app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),47);
                
                X = [-w/2 w/2 w/2 -w/2 -w/2];
                Y = [h/2 h/2 -h/2 -h/2 h/2];
                P = [X;Y];
                ct = cos(theta);
                st = sin(theta);
                R = [ct -st;st ct];
                P = R * P;
                
                if (isempty(app.BLUEFigHdl) ~= 1) 
                    
                   % Draw the line showing the direction of the front face:
                    C_bI_BLUE =  [  cos(theta) sin(theta)
                                   -sin(theta) cos(theta) ];
                             
                    Vec_BLUE  = C_bI_BLUE*[ 0.15
                                            0.0 ];
                                                  
                    app.BLUEFigLine.XData = [a,a+Vec_BLUE(2)]; 
                    app.BLUEFigLine.YData = [b,b+Vec_BLUE(1)];  
                    app.BLUEFigHdl.XData = P(1,:)+a;
                    app.BLUEFigHdl.YData = P(2,:)+b;
                    
                end                    
      
                app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(app.DataPacketUp(round((changingValue/100)*size(app.DataPacketUp,1)),1)),' s');
            end
        end

        % Button pushed function: CopyCodeToPlatformsButton
        function CopyCodeToPlatformsButtonPushed(app, event)
            % Use the system command to copy files from the Pi to
            % windows
            
            delete([cd '\Pi_Files\*']);
            delete([cd '\Batch_Scripts\*']);
                
            fprintf('\nCreating Batch_Scripts based on user selected options...')
            app.AutoGenerateBatchCommands;
            fprintf('Done!\n\n')
            
            fprintf('Copying files from Pi to Windows...')
            system([cd '\Batch_Scripts\CopyDirToWinAuto.bat > NUL']);
            fprintf('Done!\n\n')
            
            if (app.REDCheckBox.Value == 1)
                
                fprintf('Checking connection to RED platform...\n')
                [~,results] = system(['ping 192.168.0.100 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    
                    fprintf('Connection successful!\n');
                    
                    fprintf('Copying files from Windows to RED...')
                    system([cd '\Batch_Scripts\CopyDirToREDAuto.bat > NUL']);
                    
                    RED = raspberrypi('192.168.0.100','pi','raspberry');
                    system(RED,'sudo chmod -R 755 ExperimentSoftware');
                    
                    fprintf('Done!\n\n')
                else 
                    fprintf('There was a problem connecting to RED, files not copied.\n')
                end
           
            end
            
            if (app.BLACKCheckBox.Value == 1)
                
                fprintf('Checking connection to BLACK platform...\n')
                [~,results] = system(['ping 192.168.0.101 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    fprintf('Connection successful!\n');
                    
                    fprintf('Copying files from Windows to BLACK...')
                    system([cd '\Batch_Scripts\CopyDirToBLACKAuto.bat > NUL']);
                    
                    BLACK = raspberrypi('192.168.0.101','pi','raspberry');
                    system(BLACK,'sudo chmod -R 755 ExperimentSoftware');
                    
                    fprintf('Done!\n\n')
                else 
                    fprintf('There was a problem connecting to BLACK, files not copied.\n')
                end                
                
            end
                   
            if (app.BLUECheckBox.Value == 1)
                
                fprintf('Checking connection to BLUE platform...\n')
                [~,results] = system(['ping 192.168.0.102 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    fprintf('Connection successful!\n');
                    
                    fprintf('Copying files from Windows to BLUE...')
                    system([cd '\Batch_Scripts\CopyDirToBLUEAuto.bat > NUL']);
                    
                    BLUE = raspberrypi('192.168.0.102','pi','raspberry');
                    system(BLUE,'sudo chmod -R 755 ExperimentSoftware');
                    
                    fprintf('Done!\n\n')
                else 
                    fprintf('There was a problem connecting to BLUE, files not copied.\n')
                end                      
                
            end
            
        end

        % Button pushed function: 
        % RefreshSoftwareonSelectedPlatformsButton
        function RefreshSoftwareonSelectedPlatformsButtonPushed(app, event)
             
            answer = questdlg('Warning: You are about to PERMANENTLY DELETE all files under /home/pi/ExpeirmentSoftware/MATLAB_ws/. This action cannot be undone','Raspberry Pi Cleanup Warning','Understood','Cancel','Cancel');
            
            if answer ~= "Cancel"
                
                fprintf('Checking connection to GS...\n')
                [~,results] = system(['ping 192.168.0.106 -w 2000 -n ' num2str(1)]);
                if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                    
                    fprintf('Connection successful!\n');
                    
                    fprintf('Clearing out files on GS...')
                    
                    try
                        GS = raspberrypi('192.168.0.106','pi','raspberry');                    
                        system(GS, 'rm -r /home/pi/ExperimentSoftware/MATLAB_ws/*');
                    catch
                            %Do Nothing
                    end
                    
                    
                    fprintf('Done!\n\n')
                else 
                    fprintf('There was a problem connecting to GS, files not deleted.\n')
                end
                
            
                if (app.REDCheckBox.Value == 1)
                    
                    fprintf('Checking connection to RED platform...\n')
                    [~,results] = system(['ping 192.168.0.100 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out files on RED...')
                        
                        try
                            RED = raspberrypi('192.168.0.100','pi','raspberry');                    
                            system(RED, 'rm -r /home/pi/ExperimentSoftware/MATLAB_ws/*');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to RED, files not deleted.\n')
                    end
               
                end
                
                if (app.BLACKCheckBox.Value == 1)
                    
                    fprintf('Checking connection to BLACK platform...\n')
                    [~,results] = system(['ping 192.168.0.101 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out files on BLACK...')
                        
                        try
                            BLACK = raspberrypi('192.168.0.101','pi','raspberry');                    
                            system(BLACK, 'rm -r /home/pi/ExperimentSoftware/MATLAB_ws/*');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLACK, files not deleted.\n')
                    end
               
                end
    
                if (app.BLUECheckBox.Value == 1)
                    
                    fprintf('Checking connection to BLUE platform...\n')
                    [~,results] = system(['ping 192.168.0.102 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
                    if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0
                        
                        fprintf('Connection successful!\n');
                        
                        fprintf('Clearing out files on BLUE...')
                        try
                            BLUE = raspberrypi('192.168.0.102','pi','raspberry');                    
                            system(BLUE, 'rm -r /home/pi/ExperimentSoftware/MATLAB_ws/*');
                        catch
                            %Do Nothing
                        end
                        
                        fprintf('Done!\n\n')
                    else 
                        fprintf('There was a problem connecting to BLUE, files not deleted.\n')
                    end
               
                end
                
                try
                    rmdir([cd '\Pi_Files\MATLAB_ws\'],'s');
                catch
                        %Do Nothing
                end
                                
            else
                
                %Do Nothing
                
            end
            
        end

        % Value changed function: RealTimeTelemetryCheckBox
        function RealTimeTelemetryCheckBoxValueChanged(app, event)
            value = app.RealTimeTelemetryCheckBox.Value;
            FileName = app.AvailableDiagramsDropDown.Value;
            
            [~,results] = system(['ping 192.168.0.100 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0                
                RED = raspberrypi('192.168.0.100','pi','raspberry');
            end
            [~,results] = system(['ping 192.168.0.101 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0                
                BLACK = raspberrypi('192.168.0.101','pi','raspberry');
            end
            [~,results] = system(['ping 192.168.0.102 -w 2000 -n ' num2str(app.REDPingTimeout.Value)]);
            if contains(results,'Destination host unreachable') == 0 && contains(results,'Request timed out') == 0                
                BLUE = raspberrypi('192.168.0.102','pi','raspberry');
            end
            
            while value == 1   
                                
                % Bind UDP port to receive real-time data                
                hudpr = dsp.UDPReceiver('LocalIPPort',25001,'RemoteIPAddress','192.168.0.106','ReceiveBufferSize' ,8192,'MessageDataType','double');
                
                % Get latest data packet:
                dataR = hudpr();
                
                if size(dataR) ~= 16
                    
                    continue;
                    
                end
                
                if isModelRunning(RED,FileName(1:end-4)) == true
                
                    % Draw the platforms:
                    a = dataR(3);
                    b = dataR(2);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataR(4);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                        
                    if (isempty(app.REDFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_RED = [  cos(theta) sin(theta)
                                     -sin(theta) cos(theta) ];
                                 
                        Vec_RED  = C_bI_RED*[ 0.15
                                              0.0 ];
                                                      
                        app.REDFigLine.XData = [a,a+Vec_RED(2)]; 
                        app.REDFigLine.YData = [b,b+Vec_RED(1)];
                        app.REDFigHdl.XData = P(1,:)+a;
                        app.REDFigHdl.YData = P(2,:)+b;
                    end
                    
                end
                
                if isModelRunning(BLACK,FileName(1:end-4)) == true
                    
                    a = dataR(6);
                    b = dataR(5);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataR(7);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.BLACKFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_BLACK = [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];
                                 
                        Vec_BLACK  = C_bI_BLACK*[ 0.15
                                                  0.0 ];
                                                      
                        app.BLACKFigLine.XData = [a,a+Vec_BLACK(2)]; 
                        app.BLACKFigLine.YData = [b,b+Vec_BLACK(1)];
                        app.BLACKFigHdl.XData = P(1,:)+a;
                        app.BLACKFigHdl.YData = P(2,:)+b;
                        
                    end
                    
                end
                
                if isModelRunning(BLUE,FileName(1:end-4)) == true
                            
                    a = dataR(15);
                    b = dataR(14);
                    w = 0.3;
                    h = 0.3;
                    theta = -dataR(16);
                    
                    X = [-w/2 w/2 w/2 -w/2 -w/2];
                    Y = [h/2 h/2 -h/2 -h/2 h/2];
                    P = [X;Y];
                    ct = cos(theta);
                    st = sin(theta);
                    R = [ct -st;st ct];
                    P = R * P;
                    
                    if (isempty(app.BLUEFigHdl) ~= 1) 
                        
                       % Draw the line showing the direction of the front face:
                        C_bI_BLUE =  [  cos(theta) sin(theta)
                                       -sin(theta) cos(theta) ];
                                 
                        Vec_BLUE  = C_bI_BLUE*[ 0.15
                                                0.0 ];
                                                      
                        app.BLUEFigLine.XData = [a,a+Vec_BLUE(2)]; 
                        app.BLUEFigLine.YData = [b,b+Vec_BLUE(1)];  
                        app.BLUEFigHdl.XData = P(1,:)+a;
                        app.BLUEFigHdl.YData = P(2,:)+b;
                        
                    end       
                end
          
                %app.ExperimentPreview.Children(1).String = strcat('Time: ',num2str(dataR(1)),' s');
                
                pause(0.01)
                drawnow limitrate
                    
            end   
             
            if value == 0 && exists('hudpr')
                release(hudpr);
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SPOT30GUIUIFigure = uifigure('Visible', 'off');
            app.SPOT30GUIUIFigure.Name = 'SPOT 3.0 GUI';
            app.FileMenu = uimenu(app.SPOT30GUIUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveGUIMenu = uimenu(app.FileMenu);
            app.SaveGUIMenu.Tooltip = {'Coming Soon!'};
            app.SaveGUIMenu.Enable = 'off';
            app.SaveGUIMenu.Text = 'Save GUI';
            app.LabLinksMenu = uimenu(app.FileMenu);
            app.LabLinksMenu.Text = 'Lab Links';
            app.GitHubMenu = uimenu(app.LabLinksMenu);
            app.GitHubMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubMenuSelected, true);
            app.GitHubMenu.Text = 'GitHub';
            app.GooglePhotosMenu = uimenu(app.LabLinksMenu);
            app.GooglePhotosMenu.MenuSelectedFcn = createCallbackFcn(app, @GooglePhotosMenuSelected, true);
            app.GooglePhotosMenu.Text = 'Google Photos';
            app.YoutubeChannelMenu = uimenu(app.LabLinksMenu);
            app.YoutubeChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @YoutubeChannelMenuSelected, true);
            app.YoutubeChannelMenu.Text = 'Youtube Channel';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.Text = 'Help';
            app.YouTubeTutorialsMenu = uimenu(app.HelpMenu);
            app.YouTubeTutorialsMenu.Text = 'YouTube Tutorials';
            app.RunningaSimulationandExperimentMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RunningaSimulationandExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @RunningaSimulationandExperimentMenuSelected, true);
            app.RunningaSimulationandExperimentMenu.Text = 'Running a Simulation and Experiment';
            app.ConnectingtoaRaspberryPiRemotelyMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ConnectingtoaRaspberryPiRemotelyMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectingtoaRaspberryPiRemotelyMenuSelected, true);
            app.ConnectingtoaRaspberryPiRemotelyMenu.Text = 'Connecting to a Raspberry Pi Remotely';
            app.RemovingtheTableCoverMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RemovingtheTableCoverMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovingtheTableCoverMenuSelected, true);
            app.RemovingtheTableCoverMenu.Text = 'Removing the Table Cover';
            app.RetrievingtheIsopropanolMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RetrievingtheIsopropanolMenu.MenuSelectedFcn = createCallbackFcn(app, @RetrievingtheIsopropanolMenuSelected, true);
            app.RetrievingtheIsopropanolMenu.Text = 'Retrieving the Isopropanol';
            app.TableCleaningProceduresMenu = uimenu(app.YouTubeTutorialsMenu);
            app.TableCleaningProceduresMenu.MenuSelectedFcn = createCallbackFcn(app, @TableCleaningProceduresMenuSelected, true);
            app.TableCleaningProceduresMenu.Text = 'Table Cleaning Procedures';
            app.SavingExperimentDataMenu = uimenu(app.YouTubeTutorialsMenu);
            app.SavingExperimentDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavingExperimentDataMenuSelected, true);
            app.SavingExperimentDataMenu.Text = 'Saving Experiment Data';
            app.RemovingtheRoboticArmMenu = uimenu(app.YouTubeTutorialsMenu);
            app.RemovingtheRoboticArmMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovingtheRoboticArmMenuSelected, true);
            app.RemovingtheRoboticArmMenu.Text = 'Removing the Robotic Arm';
            app.ChargingaLEDBatteryPackMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ChargingaLEDBatteryPackMenu.MenuSelectedFcn = createCallbackFcn(app, @ChargingaLEDBatteryPackMenuSelected, true);
            app.ChargingaLEDBatteryPackMenu.Text = 'Charging a LED Battery Pack';
            app.ChargingaPlatformMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ChargingaPlatformMenu.MenuSelectedFcn = createCallbackFcn(app, @ChargingaPlatformMenuSelected, true);
            app.ChargingaPlatformMenu.Text = 'Charging a Platform';
            app.InstallinganAirTankMenu = uimenu(app.YouTubeTutorialsMenu);
            app.InstallinganAirTankMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallinganAirTankMenuSelected, true);
            app.InstallinganAirTankMenu.Text = 'Installing an Air Tank';
            app.InstallingtheRoboticArmMenu = uimenu(app.YouTubeTutorialsMenu);
            app.InstallingtheRoboticArmMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallingtheRoboticArmMenuSelected, true);
            app.InstallingtheRoboticArmMenu.Text = 'Installing the Robotic Arm';
            app.ForkingtheSPOTRepositoryMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ForkingtheSPOTRepositoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ForkingtheSPOTRepositoryMenuSelected, true);
            app.ForkingtheSPOTRepositoryMenu.Text = 'Forking the SPOT Repository';
            app.PlacingtheTableCoverMenu = uimenu(app.YouTubeTutorialsMenu);
            app.PlacingtheTableCoverMenu.MenuSelectedFcn = createCallbackFcn(app, @PlacingtheTableCoverMenuSelected, true);
            app.PlacingtheTableCoverMenu.Text = 'Placing the Table Cover';
            app.FillinganAirTankMenu = uimenu(app.YouTubeTutorialsMenu);
            app.FillinganAirTankMenu.MenuSelectedFcn = createCallbackFcn(app, @FillinganAirTankMenuSelected, true);
            app.FillinganAirTankMenu.Text = 'Filling an Air Tank';
            app.ExportingVideoswithQCViewerMenu = uimenu(app.YouTubeTutorialsMenu);
            app.ExportingVideoswithQCViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportingVideoswithQCViewerMenuSelected, true);
            app.ExportingVideoswithQCViewerMenu.Text = 'Exporting Videos with QC Viewer';
            app.CleaningthePucksandMovingaPlatformMenu = uimenu(app.YouTubeTutorialsMenu);
            app.CleaningthePucksandMovingaPlatformMenu.MenuSelectedFcn = createCallbackFcn(app, @CleaningthePucksandMovingaPlatformMenuSelected, true);
            app.CleaningthePucksandMovingaPlatformMenu.Text = 'Cleaning the Pucks and Moving a Platform';
            app.ToolsMenu = uimenu(app.SPOT30GUIUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CheckArmConfigurationMenu = uimenu(app.ToolsMenu);
            app.CheckArmConfigurationMenu.Tooltip = {'Coming Soon!'};
            app.CheckArmConfigurationMenu.Enable = 'off';
            app.CheckArmConfigurationMenu.Text = 'Check Arm Configuration';
            app.GridLayout = uigridlayout(app.SPOT30GUIUIFigure);
            app.MassPropertiesPanel = uipanel(app.GridLayout);
            app.MassPropertiesPanel.Title = 'Mass Properties';
            app.GridLayout2 = uigridlayout(app.MassPropertiesPanel);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITableGeometry = uitable(app.GridLayout2);
            app.UITableGeometry.ColumnName = {''};
            app.UITableGeometry.RowName = {};
            app.UITableGeometry.CellEditCallback = createCallbackFcn(app, @UITableGeometryCellEdit, true);
            app.UITableArmAngle = uitable(app.GridLayout2);
            app.UITableArmAngle.ColumnName = {''};
            app.UITableArmAngle.RowName = {};
            app.UITableArmAngle.CellEditCallback = createCallbackFcn(app, @UITableArmAngleCellEdit, true);
            app.ShowDefsButton = uibutton(app.GridLayout2, 'push');
            app.ShowDefsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDefsButtonPushed, true);
            app.ShowDefsButton.Text = 'Show Defs';
            app.ConfirmOptionsButton = uibutton(app.GridLayout, 'push');
            app.ConfirmOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmOptionsButtonPushed, true);
            app.ConfirmOptionsButton.Tooltip = {'Click to confirm the options selected above. This will load in the simulation figure and enable the next steps. Note that this button is only enable after loading the desired diagram (i.e. Load Simulation Diagram button must be clicked).'};
            app.ConfirmOptionsButton.Text = 'Confirm Options';
            app.LoadSelectedDiagramButton = uibutton(app.GridLayout, 'push');
            app.LoadSelectedDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectedDiagramButtonPushed, true);
            app.LoadSelectedDiagramButton.Tooltip = {'Load the diagram currently selected in the dropdown menu.'};
            app.LoadSelectedDiagramButton.Text = 'Load Selected Diagram';
            app.PlatformControlsPanel = uipanel(app.GridLayout);
            app.PlatformControlsPanel.Title = 'Platform Controls';
            app.GridLayout8 = uigridlayout(app.PlatformControlsPanel);
            app.ExperimentOptionsPanel = uipanel(app.GridLayout8);
            app.ExperimentOptionsPanel.Title = 'Experiment Options';
            app.GridLayout9 = uigridlayout(app.ExperimentOptionsPanel);
            app.StartExperimentButton = uibutton(app.GridLayout9, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Tooltip = {'Start the selected experiment (experiment must have been compiled first).'};
            app.StartExperimentButton.Text = 'Start Experiment';
            app.StopExperimentButton = uibutton(app.GridLayout9, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Tooltip = {'Stop the selected experiment.'};
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveExperimentButton = uibutton(app.GridLayout9, 'push');
            app.SaveExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExperimentButtonPushed, true);
            app.SaveExperimentButton.Tooltip = {'Copy the experiment data from each Pi and save it to Saved Data.'};
            app.SaveExperimentButton.Text = 'Save Experiment';
            app.RealTimeTelemetryCheckBox = uicheckbox(app.GridLayout9);
            app.RealTimeTelemetryCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealTimeTelemetryCheckBoxValueChanged, true);
            app.RealTimeTelemetryCheckBox.Tooltip = {'Receive data from the running diagram and display it in the GUI. (DISABLED: Buggy).'};
            app.RealTimeTelemetryCheckBox.Text = 'Real-Time Telemetry';
            app.DeployCodeREDButton = uibutton(app.GridLayout9, 'push');
            app.DeployCodeREDButton.ButtonPushedFcn = createCallbackFcn(app, @DeployCodeREDButtonPushed, true);
            app.DeployCodeREDButton.Tooltip = {'Build the code for the selected diagram to the Ground Station Pi located at 192.168.0.106.'};
            app.DeployCodeREDButton.Text = 'Build Code';
            app.CopyCodeToPlatformsButton = uibutton(app.GridLayout9, 'push');
            app.CopyCodeToPlatformsButton.ButtonPushedFcn = createCallbackFcn(app, @CopyCodeToPlatformsButtonPushed, true);
            app.CopyCodeToPlatformsButton.Tooltip = {'Copies the MATLAB_ws from the Ground Station Pi to the Windows PC, then moves it to each platform based on which active platforms have been selected.'};
            app.CopyCodeToPlatformsButton.Text = {'Copy Code'; 'to Platforms'};
            app.RefreshSoftwareonSelectedPlatformsButton = uibutton(app.GridLayout9, 'push');
            app.RefreshSoftwareonSelectedPlatformsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshSoftwareonSelectedPlatformsButtonPushed, true);
            app.RefreshSoftwareonSelectedPlatformsButton.Tooltip = {'Completely empties the MATLAB_ws folder on all active platforms to facilitate troubleshooting. Only use if you believe that there is a conflict in which compiled diagram is being used when you start an experiment (for example, if you make a change, compile, but do not see that change reflected).'};
            app.RefreshSoftwareonSelectedPlatformsButton.Text = {'Refresh Software'; 'on Selected Platforms'};
            app.TabGroup = uitabgroup(app.GridLayout8);
            app.REDControlsTab = uitab(app.TabGroup);
            app.REDControlsTab.Title = 'RED Controls';
            app.GridLayout10 = uigridlayout(app.REDControlsTab);
            app.REDPucksOnButton = uibutton(app.GridLayout10, 'push');
            app.REDPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @REDPucksOnButtonPushed, true);
            app.REDPucksOnButton.Tooltip = {'Turn on the pucks to float the platform.'};
            app.REDPucksOnButton.Text = 'Pucks On';
            app.REDPucksOffButton = uibutton(app.GridLayout10, 'push');
            app.REDPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @REDPucksOffButtonPushed, true);
            app.REDPucksOffButton.Tooltip = {'Turn off the pucks to float the platform.'};
            app.REDPucksOffButton.Text = 'Pucks Off';
            app.REDDebuggingPanel = uipanel(app.GridLayout10);
            app.REDDebuggingPanel.Title = 'Debugging';
            app.GridLayout13 = uigridlayout(app.REDDebuggingPanel);
            app.FireREDThrustersButton = uibutton(app.GridLayout13, 'push');
            app.FireREDThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireREDThrustersButtonPushed, true);
            app.FireREDThrustersButton.Tooltip = {'Fire the thrusters for RED in sequential order (1,2,3...).'};
            app.FireREDThrustersButton.Text = 'Fire Thrusters';
            app.PingREDButton = uibutton(app.GridLayout13, 'push');
            app.PingREDButton.ButtonPushedFcn = createCallbackFcn(app, @PingREDButtonPushed, true);
            app.PingREDButton.Tooltip = {'Send a ping to RED. If RED''s Pi is powered on, it will indicate a successful ping.'};
            app.PingREDButton.Text = 'Ping RP3';
            app.REDTextArea = uitextarea(app.GridLayout13);
            app.REDTextArea.Editable = 'off';
            app.OpenREDCMDShell = uibutton(app.GridLayout13, 'push');
            app.OpenREDCMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenREDCMDShellButtonPushed, true);
            app.OpenREDCMDShell.Tooltip = {'Open a command shell connected to the Pi on RED. For debugging.'};
            app.OpenREDCMDShell.Text = 'Open CMD Shell';
            app.TimeoutsEditFieldLabel = uilabel(app.GridLayout13);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.REDPingTimeout = uieditfield(app.GridLayout13, 'numeric');
            app.REDPingTimeout.Value = 1;
            app.REDConnectedLamp = uilamp(app.GridLayout13);
            app.BLACKControlsTab = uitab(app.TabGroup);
            app.BLACKControlsTab.Title = 'BLACK Controls';
            app.GridLayout11 = uigridlayout(app.BLACKControlsTab);
            app.BLACKPucksOnButton = uibutton(app.GridLayout11, 'push');
            app.BLACKPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKPucksOnButtonPushed, true);
            app.BLACKPucksOnButton.Tooltip = {'Turn on the pucks to float the platform.'};
            app.BLACKPucksOnButton.Text = 'Pucks On';
            app.BLACKPucksOffButton = uibutton(app.GridLayout11, 'push');
            app.BLACKPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @BLACKPucksOffButtonPushed, true);
            app.BLACKPucksOffButton.Tooltip = {'Turn off the pucks to float the platform.'};
            app.BLACKPucksOffButton.Text = 'Pucks Off';
            app.BLACKDebuggingPanel = uipanel(app.GridLayout11);
            app.BLACKDebuggingPanel.Title = 'Debugging';
            app.GridLayout14 = uigridlayout(app.BLACKDebuggingPanel);
            app.FireBLACKThrustersButton = uibutton(app.GridLayout14, 'push');
            app.FireBLACKThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireBLACKThrustersButtonPushed, true);
            app.FireBLACKThrustersButton.Tooltip = {'Fire the thrusters for BLACK in sequential order (1,2,3...).'};
            app.FireBLACKThrustersButton.Text = 'Fire Thrusters';
            app.PingBLACKButton = uibutton(app.GridLayout14, 'push');
            app.PingBLACKButton.ButtonPushedFcn = createCallbackFcn(app, @PingBLACKButtonPushed, true);
            app.PingBLACKButton.Tooltip = {'Send a ping to BLACK. If BLACK''s Pi is powered on, it will indicate a successful ping.'};
            app.PingBLACKButton.Text = 'Ping RP3';
            app.BLACKConnectedLamp = uilamp(app.GridLayout14);
            app.BLACKTextArea = uitextarea(app.GridLayout14);
            app.BLACKTextArea.Editable = 'off';
            app.OpenBLACKCMDShell = uibutton(app.GridLayout14, 'push');
            app.OpenBLACKCMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenBLACKCMDShellButtonPushed, true);
            app.OpenBLACKCMDShell.Tooltip = {'Open a command shell connected to the Pi on BLACK. For debugging.'};
            app.OpenBLACKCMDShell.Text = 'Open CMD Shell';
            app.TimeoutsEditFieldLabel_2 = uilabel(app.GridLayout14);
            app.TimeoutsEditFieldLabel_2.Text = 'Timeout (s)';
            app.BLACKPingTimeout = uieditfield(app.GridLayout14, 'numeric');
            app.BLACKPingTimeout.Value = 1;
            app.BLUEControlsTab = uitab(app.TabGroup);
            app.BLUEControlsTab.Title = 'BLUE Controls';
            app.GridLayout12 = uigridlayout(app.BLUEControlsTab);
            app.BLUEPucksOnButton = uibutton(app.GridLayout12, 'push');
            app.BLUEPucksOnButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEPucksOnButtonPushed, true);
            app.BLUEPucksOnButton.Tooltip = {'Turn on the pucks to float the platform.'};
            app.BLUEPucksOnButton.Text = 'Pucks On';
            app.BLUEPucksOffButton = uibutton(app.GridLayout12, 'push');
            app.BLUEPucksOffButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEPucksOffButtonPushed, true);
            app.BLUEPucksOffButton.Tooltip = {'Turn off the pucks to float the platform.'};
            app.BLUEPucksOffButton.Text = 'Pucks Off';
            app.BLUEDebuggingPanel = uipanel(app.GridLayout12);
            app.BLUEDebuggingPanel.Title = 'Debugging';
            app.GridLayout15 = uigridlayout(app.BLUEDebuggingPanel);
            app.FireBLUEThrustersButton = uibutton(app.GridLayout15, 'push');
            app.FireBLUEThrustersButton.ButtonPushedFcn = createCallbackFcn(app, @FireBLUEThrustersButtonPushed, true);
            app.FireBLUEThrustersButton.Tooltip = {'Fire the thrusters for BLUE in sequential order (1,2,3...).'};
            app.FireBLUEThrustersButton.Text = 'Fire Thrusters';
            app.PingBLUEButton = uibutton(app.GridLayout15, 'push');
            app.PingBLUEButton.ButtonPushedFcn = createCallbackFcn(app, @PingBLUEButtonPushed, true);
            app.PingBLUEButton.Tooltip = {'Send a ping to BLUE. If BLUE''s Pi is powered on, it will indicate a successful ping.'};
            app.PingBLUEButton.Text = 'Ping RP3';
            app.BLUETextArea = uitextarea(app.GridLayout15);
            app.BLUETextArea.Editable = 'off';
            app.BLUEConnectedLamp = uilamp(app.GridLayout15);
            app.OpenBLUECMDShell = uibutton(app.GridLayout15, 'push');
            app.OpenBLUECMDShell.ButtonPushedFcn = createCallbackFcn(app, @OpenBLUECMDShellButtonPushed, true);
            app.OpenBLUECMDShell.Tooltip = {'Open a command shell connected to the Pi on BLUE. For debugging.'};
            app.OpenBLUECMDShell.Text = 'Open CMD Shell';
            app.TimeoutsEditFieldLabel_3 = uilabel(app.GridLayout15);
            app.TimeoutsEditFieldLabel_3.Text = 'Timeout (s)';
            app.BLUEPingTimeout = uieditfield(app.GridLayout15, 'numeric');
            app.BLUEPingTimeout.Value = 1;
            app.SimulationPanel = uipanel(app.GridLayout);
            app.SimulationPanel.Title = 'Simulation';
            app.GridLayout7 = uigridlayout(app.SimulationPanel);
            app.ExperimentPreview = uiaxes(app.GridLayout7);
            xlabel(app.ExperimentPreview, 'Y-Coordinate (m)')
            ylabel(app.ExperimentPreview, 'X-Coordinate (m)')
            app.SimulationControlsPanel = uipanel(app.GridLayout7);
            app.SimulationControlsPanel.Title = 'Simulation Controls';
            app.GridLayout5 = uigridlayout(app.SimulationControlsPanel);
            app.RunSimulationButton = uibutton(app.GridLayout5, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Tooltip = {'Click to run the simulation for the currently loaded (and selected) diagram in the Available Diagrams dropdown menu.'};
            app.RunSimulationButton.Text = 'Run Simulation';
            app.PlayAnimationButton = uibutton(app.GridLayout5, 'push');
            app.PlayAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAnimationButtonPushed, true);
            app.PlayAnimationButton.Tooltip = {'Play the animation for the simulation.'};
            app.PlayAnimationButton.Text = 'Play Animation';
            app.AnimationSpeedButtonGroup = uibuttongroup(app.GridLayout5);
            app.AnimationSpeedButtonGroup.Title = 'Animation Speed';
            app.AnimationSpeedSlider = uislider(app.AnimationSpeedButtonGroup);
            app.AnimationSpeedSlider.MajorTicks = [0 100];
            app.AnimationSpeedSlider.MajorTickLabels = {'Fast', 'Slow'};
            app.AnimationSpeedSlider.Tooltip = {'Change the speed of the animation. NOTE: Depending on speed, animation may buffer and not stop immediately.'};
            app.StopAnimationButton = uibutton(app.GridLayout5, 'state');
            app.StopAnimationButton.Tooltip = {'Stop the animation.'};
            app.StopAnimationButton.Text = 'Stop Animation';
            app.SimExportOptionsPanel = uipanel(app.GridLayout7);
            app.SimExportOptionsPanel.Title = 'Export Options';
            app.GridLayout6 = uigridlayout(app.SimExportOptionsPanel);
            app.CreateVideoCheckBox = uicheckbox(app.GridLayout6);
            app.CreateVideoCheckBox.Tooltip = {'If checked, simulation animation is saved to as a video. (DISABLED: currently buggy).'};
            app.CreateVideoCheckBox.Text = 'Create Video';
            app.SaveSimulationButton = uibutton(app.GridLayout6, 'push');
            app.SaveSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSimulationButtonPushed, true);
            app.SaveSimulationButton.Tooltip = {'Save the simulation data to the Saved Data folder.'};
            app.SaveSimulationButton.Text = 'Save Simulation ';
            app.Slider = uislider(app.GridLayout7);
            app.Slider.MajorTicks = [0 100];
            app.Slider.MajorTickLabels = {'Start', 'End'};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.AnimationScrubberLabel = uilabel(app.GridLayout7);
            app.AnimationScrubberLabel.Text = 'Animation Scrubber';
            app.AvailableDiagramsDropDownLabel = uilabel(app.GridLayout);
            app.AvailableDiagramsDropDownLabel.Text = 'Available Diagrams:';
            app.AvailableDiagramsDropDown = uidropdown(app.GridLayout);
            app.ActivePlatformsPanel = uipanel(app.GridLayout);
            app.ActivePlatformsPanel.Title = 'Active Platforms';
            app.GridLayout4 = uigridlayout(app.ActivePlatformsPanel);
            app.REDCheckBox = uicheckbox(app.GridLayout4);
            app.REDCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDCheckBoxValueChanged, true);
            app.REDCheckBox.Tooltip = {'Select to enable RED for the simulation and experiment.'};
            app.REDCheckBox.Text = 'RED ';
            app.BLACKCheckBox = uicheckbox(app.GridLayout4);
            app.BLACKCheckBox.ValueChangedFcn = createCallbackFcn(app, @BLACKCheckBoxValueChanged, true);
            app.BLACKCheckBox.Tooltip = {'Select to enable BLACK for the simulation and experiment.'};
            app.BLACKCheckBox.Text = 'BLACK ';
            app.BLUECheckBox = uicheckbox(app.GridLayout4);
            app.BLUECheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUECheckBoxValueChanged, true);
            app.BLUECheckBox.Tooltip = {'Select to enable BLUE for the simulation and experiment.'};
            app.BLUECheckBox.Text = 'BLUE ';
            app.ARMCheckBox = uicheckbox(app.GridLayout4);
            app.ARMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ARMCheckBoxValueChanged, true);
            app.ARMCheckBox.Tooltip = {'Select to enable the ARM for the simulation and experiment (DISABLED: robotic arm acnnot be simulated with current design architechture).'};
            app.ARMCheckBox.Text = 'ARM';
            app.AuxParametersPanel = uipanel(app.GridLayout);
            app.AuxParametersPanel.Title = 'Aux Parameters';
            app.GridLayout3 = uigridlayout(app.AuxParametersPanel);
            app.TimeLabel = uilabel(app.GridLayout3);
            app.TimeLabel.Text = 'Time:';
            app.TotalTimeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TotalTimeEditField.ValueDisplayFormat = '%.1f';
            app.TotalTimeEditField.Editable = 'off';
            app.TotalTimeEditField.Tooltip = {'Total length of the simulation/experiment. Can''t be edited in the GUI.'};
            app.TotalTimeEditField.Value = 500;
            app.sLabel = uilabel(app.GridLayout3);
            app.sLabel.Text = 's';
            app.RateLabel = uilabel(app.GridLayout3);
            app.RateLabel.Text = 'Rate:';
            app.SampleRateEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SampleRateEditField.ValueDisplayFormat = '%.f';
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.SampleRateEditField.Tooltip = {'Sampling rate of the simulation/experiment diagram. Can be edited here or in the initialization script. Does not affect the sampling rate of the PhaseSpace Server.'};
            app.SampleRateEditField.Value = 100;
            app.HzLabel = uilabel(app.GridLayout3);
            app.HzLabel.Text = 'Hz';
            % Show the figure after all components are created
            app.SPOT30GUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v3_08

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SPOT30GUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @PopulateGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPOT30GUIUIFigure)
        end
    end
end